classdef HW4_Arm_07 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        d6EditField        matlab.ui.control.NumericEditField
        d6EditFieldLabel   matlab.ui.control.Label
        th5EditField       matlab.ui.control.NumericEditField
        th5EditFieldLabel  matlab.ui.control.Label
        th4EditField       matlab.ui.control.NumericEditField
        th4EditFieldLabel  matlab.ui.control.Label
        th3EditField       matlab.ui.control.NumericEditField
        th3EditFieldLabel  matlab.ui.control.Label
        th2EditField       matlab.ui.control.NumericEditField
        th2EditFieldLabel  matlab.ui.control.Label
        th1EditField       matlab.ui.control.NumericEditField
        th1EditFieldLabel  matlab.ui.control.Label
        d6Slider           matlab.ui.control.Slider
        d6SliderLabel      matlab.ui.control.Label
        th5Slider          matlab.ui.control.Slider
        th5SliderLabel     matlab.ui.control.Label
        th4Slider          matlab.ui.control.Slider
        th4SliderLabel     matlab.ui.control.Label
        th2Slider          matlab.ui.control.Slider
        th2SliderLabel     matlab.ui.control.Label
        th3Slider          matlab.ui.control.Slider
        th3SliderLabel     matlab.ui.control.Label
        th1Slider          matlab.ui.control.Slider
        th1SliderLabel     matlab.ui.control.Label
        HomeButton         matlab.ui.control.Button
        InitialiseButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        Fb;
        Vb;

        F1;
        V1;

        F2;
        V2;

        F3;
        V3;

        F4;
        V4;

        F5;
        V5;

        F6;
        V6;

        F7;
        V7;
        
        L1 = 193;
        L2 = 196;
        L3 = 69.5;

        th1 = 0;
        th2 = 0;
        th3 = 0;
        th4 = 0;
        th5 = 0;
        d6 = 0;
       

    end
    
    methods (Access = private)
        
        function [] = drawRobot(app)
            
        
            %ah is alpha
            cla
            plot3(0,0,0,'r*','MarkerSize',10)

          
            %Link base
            %AH = ALPHA;
            pb = app.Vb;

            fcs =['g'];
            patch('faces',app.Fb, 'vertices',pb(1:3,:)','facecolor',fcs)

            %Link1
            % T1A = eye(4);
            % Trot = eye(4);
            % 
            % Trot(1:3,1:3) = rotz(app.th1);
            % T1A = T1A*Trot;
            
            c = cosd(app.th1); s=sind(app.th1);

            T1A = [c -s 0 0;
                   s  c  0 0;
                   0  0  1 0;
                   0  0  0  1]; 

            c = cosd(app.th2); s=sind(app.th2);
            T21 = [c -s 0  0;
                   0  0  1 0;
                   -s -c 0 0;
                   0   0  0 1];
             c = cosd(app.th3); s=sind(app.th3);

             T32 = [c -s 0 193;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0  1];

             c = cosd(app.th4); s=sind(app.th4);

             T43 = [c -s 0 196;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0 1];

              c = cosd(app.th5); s=sind(app.th5);
              T54 = [c -s 0 0;
                     0  0 1 69.5;
                     -s -c 0 0;
                     0   0 0 1];

            T20 = T1A * T21;
            T30 = T20 * T32;
            T40 = T30 * T43;
            T50 = T40 * T54;

            p1 = T1A*app.V1;
            fcs =['r'];
            patch('faces',app.F1, 'vertices', p1(1:3,:)','facecolor',fcs)

             p2 = T20*app.V2;
             fcs =['c'];
             patch('faces',app.F2, 'vertices',p2(1:3,:)','facecolor',fcs)

             p3 = T30*app.V3;
             fcs =['b']; 
             patch('faces',app.F3, 'vertices',p3(1:3,:)','facecolor',fcs)


             %Link4  

             p4 = T40*app.V4;
             fcs =['y']; 
             patch('faces',app.F4, 'vertices',p4(1:3,:)','facecolor',fcs)

             %Link5 
      
             p5 = T50*app.V5;
             fcs =['m']; 
             patch('faces',app.F5, 'vertices',p5(1:3,:)','facecolor',fcs)

             %Link6
             T65 = eye(4);
             Trot = eye(4);
             Trot(1:3,4) = [0 -(app.d6 - 19.75)  0 ]';
           
             T65 = T65*Trot;

             p6 = T50*T65*app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F6, 'vertices',p6(1:3,:)','facecolor',fcs)

             %Link7
             Trot = eye(4);
             Trot(1:3,1:3)=rotz(180);
             T76 = Trot*T65;

             p7 = T50 * T76 *app.V7;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F7, 'vertices',p7(1:3,:)','facecolor',fcs)


        end
        
        function results = computeT(app)
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            close all
            plot3(0,0,0)
            s = 400;
            axis ([-s s -s s -s s ])
            grid on
            xlabel('x')
            ylabel('y')
            zlabel('z')
            axis square
            hold on
            cla

            [app.Fb, app.Vb] = stlread_mod("Base.STL");
            [app.F1, app.V1] = stlread_mod("Link1.STL");
            [app.F2, app.V2] = stlread_mod("Link2.STL");
            [app.F3, app.V3] = stlread_mod("Link3.STL");
            [app.F4, app.V4] = stlread_mod("Link4.STL");
            [app.F5, app.V5] = stlread_mod("Link5.STL");
            [app.F6, app.V6] = stlread_mod("Link6.STL");
            [app.F7, app.V7] = stlread_mod("Link7.STL");


            app.drawRobot();
            
            app.InitialiseButton.Visible="off";
            app.HomeButton.Visible="on";

            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

            app.d6SliderLabel.Visible="on";
            app.d6Slider.Visible="on";

            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

            app.d6EditFieldLabel.Visible="on";
            app.d6EditField.Visible="on";

        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            app.th1 = 0;
            app.th2 = 0;
            app.th3 = 0;
            app.th4 = 0;
            app.th5= 0;
            app.d6 = 0;

            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;
            app.d6Slider.Value = 0;

            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;
            app.d6EditField.Value = 0;

            app.drawRobot();
        end

        % Value changing function: th1Slider
        function th1SliderValueChanging(app, event)
            app.th1 = event.Value;
            app.th1EditField.Value = event.Value;
            app.drawRobot();
           
            
        end

        % Value changing function: th2Slider
        function th2SliderValueChanging(app, event)
            app.th2 = -1 * event.Value;
            app.th2EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th3Slider
        function th3SliderValueChanging(app, event)
            app.th3 = event.Value;
            app.th3EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th4Slider
        function th4SliderValueChanging(app, event)
            app.th4 =  event.Value;
            app.th4EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th5Slider
        function th5SliderValueChanging(app, event)
            app.th5 = event.Value;
            app.th5EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: d6Slider
        function d6SliderValueChanging(app, event)
            app.d6 = event.Value;
            app.d6EditField.Value = event.Value;
            app.drawRobot();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Visible = 'off';
            app.HomeButton.Text = 'Home';
            app.th1SliderLabel = uilabel(app.UIFigure);
            app.th1SliderLabel.Visible = 'off';
            app.th1SliderLabel.Text = 'th1';
            app.th1Slider = uislider(app.UIFigure);
            app.th1Slider.Limits = [-360 360];
            app.th1Slider.ValueChangingFcn = createCallbackFcn(app, @th1SliderValueChanging, true);
            app.th1Slider.Visible = 'off';
            app.th3SliderLabel = uilabel(app.UIFigure);
            app.th3SliderLabel.Visible = 'off';
            app.th3SliderLabel.Text = 'th3';
            app.th3Slider = uislider(app.UIFigure);
            app.th3Slider.Limits = [-360 360];
            app.th3Slider.ValueChangingFcn = createCallbackFcn(app, @th3SliderValueChanging, true);
            app.th3Slider.Visible = 'off';
            app.th2SliderLabel = uilabel(app.UIFigure);
            app.th2SliderLabel.Visible = 'off';
            app.th2SliderLabel.Text = 'th2';
            app.th2Slider = uislider(app.UIFigure);
            app.th2Slider.Limits = [-360 360];
            app.th2Slider.ValueChangingFcn = createCallbackFcn(app, @th2SliderValueChanging, true);
            app.th2Slider.Visible = 'off';
            app.th4SliderLabel = uilabel(app.UIFigure);
            app.th4SliderLabel.Visible = 'off';
            app.th4SliderLabel.Text = 'th4';
            app.th4Slider = uislider(app.UIFigure);
            app.th4Slider.Limits = [-360 360];
            app.th4Slider.ValueChangingFcn = createCallbackFcn(app, @th4SliderValueChanging, true);
            app.th4Slider.Visible = 'off';
            app.th5SliderLabel = uilabel(app.UIFigure);
            app.th5SliderLabel.Visible = 'off';
            app.th5SliderLabel.Text = 'th5';
            app.th5Slider = uislider(app.UIFigure);
            app.th5Slider.Limits = [-360 360];
            app.th5Slider.ValueChangingFcn = createCallbackFcn(app, @th5SliderValueChanging, true);
            app.th5Slider.Visible = 'off';
            app.d6SliderLabel = uilabel(app.UIFigure);
            app.d6SliderLabel.Visible = 'off';
            app.d6SliderLabel.Text = 'd6';
            app.d6Slider = uislider(app.UIFigure);
            app.d6Slider.Limits = [0 20];
            app.d6Slider.ValueChangingFcn = createCallbackFcn(app, @d6SliderValueChanging, true);
            app.d6Slider.Visible = 'off';
            app.th1EditFieldLabel = uilabel(app.UIFigure);
            app.th1EditFieldLabel.Visible = 'off';
            app.th1EditFieldLabel.Text = 'th1';
            app.th1EditField = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField.Visible = 'off';
            app.th2EditFieldLabel = uilabel(app.UIFigure);
            app.th2EditFieldLabel.Visible = 'off';
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField.Visible = 'off';
            app.th3EditFieldLabel = uilabel(app.UIFigure);
            app.th3EditFieldLabel.Visible = 'off';
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField.Visible = 'off';
            app.th4EditFieldLabel = uilabel(app.UIFigure);
            app.th4EditFieldLabel.Visible = 'off';
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField.Visible = 'off';
            app.th5EditFieldLabel = uilabel(app.UIFigure);
            app.th5EditFieldLabel.Visible = 'off';
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField.Visible = 'off';
            app.d6EditFieldLabel = uilabel(app.UIFigure);
            app.d6EditFieldLabel.Visible = 'off';
            app.d6EditFieldLabel.Text = 'd6';
            app.d6EditField = uieditfield(app.UIFigure, 'numeric');
            app.d6EditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW4_Arm_07

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end