classdef HW7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        BackgroundSimButton           matlab.ui.control.Button
        RelaxButton                   matlab.ui.control.Button
        EnergizeButton                matlab.ui.control.Button
        NoteMoreaccuratemovesatlowerspeedsLabel  matlab.ui.control.Label
        CurrentspeedEditField         matlab.ui.control.NumericEditField
        CurrentspeedEditFieldLabel    matlab.ui.control.Label
        LowLabel                      matlab.ui.control.Label
        HighLabel                     matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
        StatusEditField               matlab.ui.control.EditField
        StatusEditFieldLabel          matlab.ui.control.Label
        ComputeInverseButton          matlab.ui.control.Button
        TransformationMatrixLabel     matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        InverseKinematicsTableLabel   matlab.ui.control.Label
        MovetosolutionEditField       matlab.ui.control.NumericEditField
        MovetosolutionEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        MovetoTargetButton            matlab.ui.control.Button
        th5EditField_2                matlab.ui.control.NumericEditField
        th5EditField_2Label           matlab.ui.control.Label
        th3EditField_2                matlab.ui.control.NumericEditField
        th3EditField_2Label           matlab.ui.control.Label
        th2EditField_2                matlab.ui.control.NumericEditField
        th2EditField_2Label           matlab.ui.control.Label
        th1EditField_2                matlab.ui.control.NumericEditField
        th1EditField_2Label           matlab.ui.control.Label
        th4EditField_2                matlab.ui.control.NumericEditField
        th4EditField_2Label           matlab.ui.control.Label
        TargetsLabel                  matlab.ui.control.Label
        ShowthevaluesLabel            matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        SleepButton                   matlab.ui.control.Button
        th5EditField                  matlab.ui.control.NumericEditField
        th5EditFieldLabel             matlab.ui.control.Label
        th4EditField                  matlab.ui.control.NumericEditField
        th4EditFieldLabel             matlab.ui.control.Label
        th3EditField                  matlab.ui.control.NumericEditField
        th3EditFieldLabel             matlab.ui.control.Label
        th2EditField                  matlab.ui.control.NumericEditField
        th2EditFieldLabel             matlab.ui.control.Label
        th1EditField                  matlab.ui.control.NumericEditField
        th1EditFieldLabel             matlab.ui.control.Label
        d6Slider                      matlab.ui.control.Slider
        d6SliderLabel                 matlab.ui.control.Label
        th5Slider                     matlab.ui.control.Slider
        th5SliderLabel                matlab.ui.control.Label
        th4Slider                     matlab.ui.control.Slider
        th4SliderLabel                matlab.ui.control.Label
        th2Slider                     matlab.ui.control.Slider
        th2SliderLabel                matlab.ui.control.Label
        th3Slider                     matlab.ui.control.Slider
        th3SliderLabel                matlab.ui.control.Label
        th1Slider                     matlab.ui.control.Slider
        th1SliderLabel                matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description

        bot;
        client;
        simulationTimer;
        jointPosListener;

        Fb;
        Vb;

        F1;
        V1;

        F2;
        V2;

        F3;
        V3;

        F4;
        V4;

        F5;
        V5;

        F6;
        V6;

        F7;
        V7;
        
        T;

        L1 = 193;
        L2 = 196;
        L3 = 69.5;
        
        %Values corresponding to the simulation
        th1 = 0;
        th2 = 0;
        th3 = 0;
        th4 = 0;
        th5 = 0;
        d6 = 0;

        %Values corresponding to the background simulation
        sth1 = 0;
        sth2 = 0;
        sth3 = 0;
        sth4 = 0;
        sth5 = 0;
        sd6 = 0;
        
        
        %Values corresponding to the robot
        rth1 = 0;
        rth2 = 0;
        rth3 = 0;
        rth4 = 0;
        rth5 = 0;
        rd6 = 0;
        
        %Values corresponding to the user specified target position
        mth1 = 0;
        mth2 = 0;
        mth3 = 0;
        mth4 = 0;
        mth5 = 0;
        md6 = 0;

        movementflag = 0;
        speed=0;
        error=0;
        sserror=1;
        Solution;
        

    end
    
    methods (Access = private)
        
        function [] = drawRobot(app)

            cla
            plot3(0,0,0,'r*','MarkerSize',10)

          
            %Link base
            pb = app.Vb;

            fcs =['g'];
            patch('faces',app.Fb, 'vertices',pb(1:3,:)','facecolor',fcs)

            %Link1
            c = cosd(app.th1); s=sind(app.th1);

            T1A = [c -s 0 0;
                   s  c  0 0;
                   0  0  1 0;
                   0  0  0  1]; 
            
            %Link2
            c = cosd(app.th2 - 77); s=sind(app.th2 - 77);
            T21 = [c -s 0  0;
                   0  0  1 0;
                   -s -c 0 0;
                   0   0  0 1];
             
            %Link3
             c = cosd(app.th3 + 79); s=sind(app.th3 + 79);
             T32 = [c -s 0 193;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0  1];
             
            %Link4
             c = cosd(app.th4 - 88); s=sind(app.th4 - 88 );
             T43 = [c -s 0 196;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0 1];
              
            %Link5ss
              c = cosd(app.th5); s=sind(app.th5);
              T54 = [c -s 0 -16;
                     0  0 1 67.5;
                     -s -c 0 0;
                     0   0 0 1];

            T20 = T1A * T21;
            T30 = T20 * T32;
            T40 = T30 * T43;
            T50 = T40 * T54;

            p1 = T1A*app.V1;
            fcs =['r'];
            patch('faces',app.F1, 'vertices', p1(1:3,:)','facecolor',fcs)

             p2 = T20*app.V2;
             fcs =['c'];
             patch('faces',app.F2, 'vertices',p2(1:3,:)','facecolor',fcs)

             p3 = T30*app.V3;
             fcs =['b']; 
             patch('faces',app.F3, 'vertices',p3(1:3,:)','facecolor',fcs)


             %Link4  

             p4 = T40*app.V4;
             fcs =['y']; 
             patch('faces',app.F4, 'vertices',p4(1:3,:)','facecolor',fcs)

             %Link5 
      
             p5 = T50*app.V5;
             fcs =['m']; 
             patch('faces',app.F5, 'vertices',p5(1:3,:)','facecolor',fcs)

             %Link6
             T65 = eye(4);
             Trot = eye(4);
             Trot(1:3,4) = [0 -(app.d6 - 19.75)  0 ]';
           
             T65 = T65*Trot;

             p6 = T50*T65*app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F6, 'vertices',p6(1:3,:)','facecolor',fcs)

             %Link7
             Trot = eye(4);
             Trot(1:3,1:3)=rotz(180);
             Trot(1:3,4) = [15 +(app.d6 - 19.75)  0]';
             T76 = Trot*T65;

             p7 = T50 * T76 *app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F7, 'vertices',p7(1:3,:)','facecolor',fcs)
             
        end
        
        function []= close_loop_control(app)
            joint_positions=[app.rth1 -1*app.rth2 -1*app.rth3 -1*(app.rth4) app.rth5]*pi/180;
            %app.bot.arm.set_joint_positions(joint_positions);
            app.bot.arm.capture_joint_positions();
            app.error=app.bot.arm.joint_commands'- joint_positions;
            app.sserror=dot(app.error,app.error); 
         
        end
        
        function [] = go_home(app)       
               
                app.StatusEditField.Value =    'Moving to home position'
                app.movementflag ="homePos"
               
                app.mth1 = 0;
                app.mth2 = 0; 
                app.mth3 = 0; 
                app.mth4 = 0;
                app.mth5 = 0;

                app.move();
               app.th1EditField.Value = app.mth1;
          app.th2EditField.Value = app.mth2;
          app.th3EditField.Value = app.mth3;
          app.th4EditField.Value = app.mth4;
          app.th5EditField.Value = app.mth5;
               app.movementflag=""
                app.StatusEditField.Value =     'I am now at the home position'
       end
        
        function [] = go_sleep(app)
          app.StatusEditField.Value =  'Moving to sleep position'
          app.movementflag="sleepPos"
        
          app.mth1 = 0;
          app.mth2 = -98;
          app.mth3 = 90; 
          app.mth4 = 44;
          app.mth5 = 0;

          app.move();
          app.th1EditField.Value = app.mth1;
          app.th2EditField.Value = app.mth2;
          app.th3EditField.Value = app.mth3;
          app.th4EditField.Value = app.mth4;
          app.th5EditField.Value = app.mth5;
          app.movementflag=""
           app.T_matrix_calc();
           
            
          app.StatusEditField.Value = 'I am now at the sleep position'
        end

        function [] = inverse_Kinematics(app)
   
        'Generating Inverse Kinematics'
            Tin = app.T; 
            px = Tin(1,4); 
            
            py = Tin(2,4); 
            pz=Tin(3,4);
            
            temp = double(atan2d(py,px));
            theta(1,:) = [temp, temp, temp+180, temp+180]; %theta1 known
            
            if abs(cosd(theta(1,1))) > 0.1
                s234 = -Tin(1,3)./cosd(theta(1,:));
            else
                s234 = -Tin(2,3)./sind(theta(1,:));
            end
            
            temp= -Tin(3,3);
            c234 = [temp temp temp temp];
            theta234 = atan2d(s234,c234); %theta234 known

            theta234(theta234<0) = theta234(theta234<0) + 360;
       

            if abs(s234) < 1e-6

                if c234 > 0

                    c1m5 = atan2d(Tin(1,2),Tin(1,1));
                    theta(5,:) = theta(1,:) - c1m5;
                
                else
                    c1p5 = atan2d(Tin(1,2),-1*Tin(1,1));
                    theta(5,:) = c1p5 - theta(1,:);

                end
                
            else   
                 theta(5,:) = atan2d(Tin(3,2)./s234,-Tin(3,1)./s234); %theta5 known
            end

            if abs(cosd(theta(1,1))) > 0.1
                A = double(px./cosd(theta(1,:)) + app.L3*s234);
            else
                A = double(py./sind(theta(1,:)) + app.L3*s234);
            end
            
            B = double(-app.L3*c234 - pz);
            
            a = double(2*A*app.L1);
            b = double(2*B*app.L1);
            c = double(A.^2 + B.^2+app.L1^2-app.L2^2);
            
            m = [1 -1 1 -1];

   
            rad = a.^2+b.^2-c.^2;
            if abs(rad) < 1.0e-3
                rad = 0;     
            end

            theta(2,:)= atan2d(b,a) + m.*atan2d(sqrt(rad),c); %theta2 known;
                              
            theta23 = atan2d(B-app.L1*sind(theta(2,:)), A-app.L1*cosd(theta(2,:)));
            
            theta(3,:)= theta23 - theta(2,:) ;%theta3 known;
            theta(4,:) = theta234 - theta23; %theta4 known;
    
             for j=1:4
                        thc1 = theta(1,j);
                        thc2 = theta(2,j);
                        thc3 = theta(3,j);
                        thc4 = theta(4,j);
                        thc5 = theta(5,j);
                  
                       Tcheck =  [sind(thc1)*sind(thc5) + cosd(thc2 + thc3 + thc4)*cosd(thc1)*cosd(thc5),   cosd(thc5)*sind(thc1) - cosd(thc2 + thc3 + thc4)*cosd(thc1)*sind(thc5), -sind(thc2 + thc3 + thc4)*cosd(thc1), cosd(thc1)*(app.L2*cosd(thc2 + thc3) + app.L1*cosd(thc2) - app.L3*sind(thc2 + thc3 + thc4));
                               cosd(thc2 + thc3 + thc4)*cosd(thc5)*sind(thc1) - cosd(thc1)*sind(thc5), - cosd(thc1)*cosd(thc5) - cosd(thc2 + thc3 + thc4)*sind(thc1)*sind(thc5), -sind(thc2 + thc3 + thc4)*sind(thc1), sind(thc1)*(app.L2*cosd(thc2 + thc3) + app.L1*cosd(thc2) - app.L3*sind(thc2 + thc3 + thc4));
                            -sind(thc2 + thc3 + thc4)*cosd(thc5),                                sind(thc2 + thc3 + thc4)*sind(thc5),          -cosd(thc2 + thc3 + thc4),          - app.L2*sind(thc2 + thc3) - app.L1*sind(thc2) - app.L3*cosd(thc2 + thc3 + thc4);
                                                        0,                                                            0,                              0,                                                                    1];
                        Tdif = Tin-Tcheck;
                        Tdif(Tdif < 1e-5) = 0;

                        if (max(max(abs(Tdif))) > 1e-6)
                            Tdif 
                          app.StatusEditField.Value =  'Error in inverse kinenatics'
                        end
         
                       
              end

            
            app.UITable.Data = theta';
  
        end
        
        function [] = T_matrix_calc(app)
        'Generating Transformation Matrix'
            app.T = [sind(app.th1)*sind(app.th5) + cosd(app.th2 + app.th3 + app.th4)*cosd(app.th1)*cosd(app.th5),   cosd(app.th5)*sind(app.th1) - cosd(app.th2 + app.th3 + app.th4)*cosd(app.th1)*sind(app.th5), -sind(app.th2 + app.th3 + app.th4)*cosd(app.th1), cosd(app.th1)*(app.L2*cosd(app.th2 + app.th3) + app.L1*cosd(app.th2) - app.L3*sind(app.th2 + app.th3 + app.th4));
                   cosd(app.th2 + app.th3 + app.th4)*cosd(app.th5)*sind(app.th1) - cosd(app.th1)*sind(app.th5), - cosd(app.th1)*cosd(app.th5) - cosd(app.th2 + app.th3 + app.th4)*sind(app.th1)*sind(app.th5), -sind(app.th2 + app.th3 + app.th4)*sind(app.th1), sind(app.th1)*(app.L2*cosd(app.th2 + app.th3) + app.L1*cosd(app.th2) - app.L3*sind(app.th2 + app.th3 + app.th4));
                            -sind(app.th2 + app.th3 + app.th4)*cosd(app.th5),                                sind(app.th2 + app.th3 + app.th4)*sind(app.th5),          -cosd(app.th2 + app.th3 + app.th4),          - app.L2*sind(app.th2 + app.th3) - app.L1*sind(app.th2) - app.L3*cosd(app.th2 + app.th3 + app.th4);
                                                        0,                                                            0,                              0,                                                                    1];
             app.UITable_2.Data = app.T;
            
        end
        
        function [] = move(app)
            app.sth1 = app.th1;
            app.sth2 = app.th2;
            app.sth3 = app.th3;
            app.sth4 = app.th4;
            app.sth5 = app.th5;

            angleDistance = ([app.sth1 app.sth2 app.sth3 app.sth4 app.sth5]- [app.mth1 app.mth2 app.mth3 app.mth4 app.mth5]) / app.speed
         
         for count=1:1:app.speed  
                'In a for loop'

                    'Th1'
                    if(app.sth1 ~= app.mth1)
                        app.sth1 = app.sth1 -  angleDistance(1,1);
                        app.rth1=-app.sth1;
                    end

                    'Th2'
                    if(app.sth2 ~= app.mth2 )
                        app.sth2 = app.sth2 -  angleDistance(1,2);
                        app.rth2=-app.sth2;
                    end

                    'Th3'
                    if(app.sth3 ~= app.mth3)
                        app.sth3 = app.sth3 - angleDistance(1,3);
                        app.rth3 =-app.sth3;
                    end

                    'Th4'
                    if(app.sth4 ~= app.mth4 )
                        app.sth4 = app.sth4 - angleDistance(1,4);
                        app.rth4=-app.sth4;
                    end

                    'Th5'
                    if(app.sth5 ~= app.mth5 )
                        app.sth5 = app.sth5 - angleDistance(1,5);
                        app.rth5 =-app.sth5;
                    end
                    
                    joint_positions=[app.rth1 -1*app.rth2 -1*app.rth3 -1*(app.rth4) app.rth5]*pi/180;
                    app.bot.arm.set_joint_positions(joint_positions);


                   % flag = 0;
                   % time = 0
                   % while(app.sserror > 0.6 || flag == 0)
                   %     time = time + 1
                   % 
                   %     flag = 1;
                   %     app.close_loop_control();
                   % 
                   %     erroar = app.sserror
                   %     if (time > 5)
                   %          break;
                   %     end
                   % 
                   % end
                  'Out of error loop'


         % angleDistance = ([app.th1 app.th2 app.th3 app.th4 app.th5]- [app.mth1 app.mth2 app.mth3 app.mth4 app.mth5]) / app.speed
         % 
         % for count=1:1:app.speed  
         %        'In a for loop'
         % 
         %            'Th1'
         %            if(app.th1 ~= app.mth1)
         %                app.th1 = app.th1 -  angleDistance(1,1);
         %                app.rth1=-app.th1;
         %            end
         % 
         %            'Th2'
         %            if(app.th2 ~= app.mth2 )
         %                app.th2 = app.th2 -  angleDistance(1,2);
         %                app.rth2=-app.th2;
         %            end
         % 
         %            'Th3'
         %            if(app.th3 ~= app.mth3)
         %                app.th3 = app.th3 - angleDistance(1,3);
         %                app.rth3 =-app.th3;
         %            end
         % 
         %            'Th4'
         %            if(app.th4 ~= app.mth4 )
         %                app.th4 = app.th4 - angleDistance(1,4);
         %                app.rth4=-app.th4;
         %            end
         % 
         %            'Th5'
         %            if(app.th5 ~= app.mth5 )
         %                app.th5 = app.th5 - angleDistance(1,5);
         %                app.rth5 =-app.th5;
         %            end
         % 
         %           flag = 0;
         %           time = 0
         %           while(app.sserror > 0.6 || flag == 0)
         %               time = time + 1
         % 
         %               flag = 1;
         %               app.close_loop_control();
         % 
         %               erroar = app.sserror
         %               if (time > 5)
         %                    break;
         %               end
         % 
         %           end
         %           'Out of error loop'
         % 
         %       'Drawing robot'
         %       app.drawRobot()
         %       pause(0.05)
         %            
               %     if (app.movementflag =="homePos")
               %             app.th1EditField.Value = app.th1;
               %             app.th2EditField.Value = app.th2;
               %             app.th3EditField.Value = app.th3;
               %             app.th4EditField.Value = app.th4;
               %             app.th5EditField.Value = app.th5;
               %     end
               % 
               %     if (app.movementflag =="sleepPos")
               %           app.th1EditField.Value = app.th1;
               %           app.th2EditField.Value = app.th2;
               %           app.th3EditField.Value = app.th3;
               %           app.th4EditField.Value = app.th4;
               %           app.th5EditField.Value = app.th5;
               %     end
               % 
               %     if (app.movementflag =="targetPos")
               %         app.th1EditField.Value = app.th1;
               %         app.th2EditField.Value = app.th2;
               %         app.th3EditField.Value = app.th3;
               %         app.th4EditField.Value = app.th4;
               %         app.th5EditField.Value = app.th5;
               % 
               %         app.th1EditField.Value = app.th1;
               %         app.th2EditField.Value = app.th2;
               %         app.th3EditField.Value = app.th3;
               %         app.th4EditField.Value = app.th4;
               %          app.th5EditField.Value = app.th5;
               % 
               %          app.th1Slider.Value = app.th1;
               %          app.th2Slider.Value = app.th2;
               %          app.th3Slider.Value = app.th3;
               %          app.th4Slider.Value  = app.th4;
               %          app.th5Slider.Value = app.th5;
               % 
               %     end
               % app.T_matrix_calc();
               % 
          end


        end


        function [] = backgroundFunction(app)
            % Create a timer object to periodically update the simulation
        app.simulationTimer = timer('ExecutionMode', 'fixedRate', ...
                                     'Period', 0.1, ... % Update frequency (in seconds)
                                     'TimerFcn', @(~,~) app.updateDrawing());
      
        % Start the timer
        start(app.simulationTimer);
        end
        
        function [] = updateDrawing(app)
            app.bot.arm.capture_joint_positions();
            current_join_pos = app.bot.arm.joint_commands'* 180/pi;
            app.th1 = current_join_pos(1,1)
            app.th2 = current_join_pos(1,2)
            app.th3 = current_join_pos(1,3)
            app.th4 = current_join_pos(1,4)
            app.th5 = current_join_pos(1,5)
                                                  
            app.drawRobot();
            pause(0.05)
            
            %moving = app.movementflag

            % if (app.movementflag =="homePos")
            %            app.th1EditField.Value = app.th1;
            %            app.th2EditField.Value = app.th2;
            %            app.th3EditField.Value = app.th3;
            %            app.th4EditField.Value = app.th4;
            %            app.th5EditField.Value = app.th5;
            %            app.T_matrix_calc();
            % 
            % 
            % elseif (app.movementflag =="sleepPos")
            %          app.th1EditField.Value = app.th1;
            %          app.th2EditField.Value = app.th2;
            %          app.th3EditField.Value = app.th3;
            %          app.th4EditField.Value = app.th4;
            %          app.th5EditField.Value = app.th5;
            %          app.T_matrix_calc();
            % 
            % 
            % elseif (app.movementflag =="targetPos")
            %        app.th1EditField.Value = app.th1;
            %        app.th2EditField.Value = app.th2;
            %        app.th3EditField.Value = app.th3;
            %        app.th4EditField.Value = app.th4;
            %        app.th5EditField.Value = app.th5;
            % 
            %        app.th1EditField.Value = app.th1;
            %        app.th2EditField.Value = app.th2;
            %        app.th3EditField.Value = app.th3;
            %        app.th4EditField.Value = app.th4;
            %         app.th5EditField.Value = app.th5;
            % 
            %         app.th1Slider.Value = app.th1;
            %         app.th2Slider.Value = app.th2;
            %         app.th3Slider.Value = app.th3;
            %         app.th4Slider.Value  = app.th4;
            %         app.th5Slider.Value = app.th5;
            %         app.T_matrix_calc();
            % 
            %     else
               %  app.movementflag  = "";
               % end

                app.th1EditField.Value = app.th1;
                app.th2EditField.Value = app.th2;
                app.th3EditField.Value = app.th3;
                app.th4EditField.Value = app.th4;
                app.th5EditField.Value = app.th5;
                app.T_matrix_calc();


    end
        
        function [] = mover(app)
          
         angleDistance = ([app.th1 app.th2 app.th3 app.th4 app.th5]- [app.mth1 app.mth2 app.mth3 app.mth4 app.mth5]) / app.speed

         for count=1:1:app.speed  
                'In a for loop'

                    'Th1'
                    if(app.th1 ~= app.mth1)
                        app.th1 = app.th1 -  angleDistance(1,1);
                        app.rth1=-app.th1;
                    end

                    'Th2'
                    if(app.th2 ~= app.mth2 )
                        app.th2 = app.th2 -  angleDistance(1,2);
                        app.rth2=-app.th2;
                    end

                    'Th3'
                    if(app.th3 ~= app.mth3)
                        app.th3 = app.th3 - angleDistance(1,3);
                        app.rth3 =-app.th3;
                    end

                    'Th4'
                    if(app.th4 ~= app.mth4 )
                        app.th4 = app.th4 - angleDistance(1,4);
                        app.rth4=-app.th4;
                    end

                    'Th5'
                    if(app.th5 ~= app.mth5 )
                        app.th5 = app.th5 - angleDistance(1,5);
                        app.rth5 =-app.th5;
                    end

                   flag = 0;
                   time = 0
                   while(app.sserror > 0.6 || flag == 0)
                       time = time + 1

                       flag = 1;
                       app.close_loop_control();

                       erroar = app.sserror
                       if (time > 5)
                            break;
                       end

                   end
                   'Out of error loop'

               'Drawing robot'
               app.drawRobot()
               pause(0.05)

                   if (app.movementflag =="homePos")
                           app.th1EditField.Value = app.th1;
                           app.th2EditField.Value = app.th2;
                           app.th3EditField.Value = app.th3;
                           app.th4EditField.Value = app.th4;
                           app.th5EditField.Value = app.th5;
                   end

                   if (app.movementflag =="sleepPos")
                         app.th1EditField.Value = app.th1;
                         app.th2EditField.Value = app.th2;
                         app.th3EditField.Value = app.th3;
                         app.th4EditField.Value = app.th4;
                         app.th5EditField.Value = app.th5;
                   end

                   if (app.movementflag =="targetPos")
                       app.th1EditField.Value = app.th1;
                       app.th2EditField.Value = app.th2;
                       app.th3EditField.Value = app.th3;
                       app.th4EditField.Value = app.th4;
                       app.th5EditField.Value = app.th5;

                       app.th1EditField.Value = app.th1;
                       app.th2EditField.Value = app.th2;
                       app.th3EditField.Value = app.th3;
                       app.th4EditField.Value = app.th4;
                        app.th5EditField.Value = app.th5;

                        app.th1Slider.Value = app.th1;
                        app.th2Slider.Value = app.th2;
                        app.th3Slider.Value = app.th3;
                        app.th4Slider.Value  = app.th4;
                        app.th5Slider.Value = app.th5;

                   end
               app.T_matrix_calc();

          end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            rosshutdown 

            close all
            plot3(0,0,0)
            s = 600;
            axis ([-s s -s s -s s ])
            grid on
            xlabel('x')
            ylabel('y')
            zlabel('z')
            axis square
            hold on
            cla

            [app.Fb, app.Vb] = stlread_mod("Base.STL");
            [app.F1, app.V1] = stlread_mod("Link1.STL");
            [app.F2, app.V2] = stlread_mod("Link2.STL");
            [app.F3, app.V3] = stlread_mod("Link3.STL");
            [app.F4, app.V4] = stlread_mod("Link4.STL");
            [app.F5, app.V5] = stlread_mod("Link5.STL");
            [app.F6, app.V6] = stlread_mod("Link6.STL");
            [app.F7, app.V7] = stlread_mod("Link7.STL");

            app.speed = 10;
            app.SpeedSlider.Value = 10;
            app.CurrentspeedEditField.Value = 10;
            
            %Default position due to coordinate systems
            app.drawRobot()
            
            app.InitializeButton.Visible="off";
            app.HomeButton.Visible="on";
            app.SleepButton.Visible = "on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";


            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

            app.ShowthevaluesLabel.Visible = "on";
            app.TargetsLabel.Visible = "on";

            app.th1EditField_2Label.Visible="on";
            app.th1EditField_2.Visible ="on";
        
            app.th2EditField_2Label.Visible="on";
            app.th2EditField_2.Visible="on";

            app.th3EditField_2Label.Visible="on";
            app.th3EditField_2.Visible="on";

            app.th4EditField_2Label.Visible="on";
            app.th4EditField_2.Visible="on";

            app.th5EditField_2Label.Visible="on";
            app.th5EditField_2.Visible="on";

            app.MovetoTargetButton.Visible = "on";
            app.ComputeInverseButton.Visible = "on";
            app.TransformationMatrixLabel.Visible = "on";
            app.UITable.Visible = "on";
            app.InverseKinematicsTableLabel.Visible = "on";
            app.MovetosolutionEditField.Visible= "on";
            app.MovetosolutionEditFieldLabel.Visible= "on";
            
            
            app.bot = InterbotixManipulatorXS("rx200", "arm", "gripper");
            app.client = rossvcclient('/rx200/torque_enable');

            %Very initial position
            %Good code
            % app.bot.arm.capture_joint_positions();
            % initial_pos = app.bot.arm.joint_commands' * 180/pi
            % 
            % app.th1 = initial_pos(1,1);
            % app.th2 = initial_pos(1,2);
            % app.th3 = initial_pos(1,3);
            % app.th4 = initial_pos(1,4);
            % app.th5 = initial_pos(1,5);
            % 
            % app.bot.gripper.open();
            % app.d6= 5;
            % 
            % app.drawRobot()
            % %Good code above
          
           app.backgroundFunction();
           app.StatusEditField.Value =    'Moving to home position'
                app.movementflag ="homePos"
               
                app.mth1 = 0;
                app.mth2 = 0; 
                app.mth3 = 0; 
                app.mth4 = 0;
                app.mth5 = 0;

                app.mover();
               app.th1EditField.Value = app.mth1;
          app.th2EditField.Value = app.mth2;
          app.th3EditField.Value = app.mth3;
          app.th4EditField.Value = app.mth4;
          app.th5EditField.Value = app.mth5;
               app.movementflag=""
                app.StatusEditField.Value =     'I am now at the home position'

           app.go_home();
           app.th1EditField.Value = 0;
           app.th2EditField.Value = 0;
           app.th3EditField.Value = 0;
           app.th4EditField.Value = 0;
           app.th5EditField.Value = 0;

           app.th1Slider.Value = 0;
           app.th2Slider.Value = 0;
           app.th3Slider.Value = 0;
           app.th4Slider.Value  = 0;
           app.th5Slider.Value = 0;

           app.th1EditField_2.Value = 0;
           app.th2EditField_2.Value = 0;
           app.th3EditField_2.Value = 0;
           app.th4EditField_2.Value = 0;
           app.th5EditField_2.Value = 0;

           app.th1 = app.th1EditField_2.Value;
            app.th2 = app.th2EditField_2.Value;
            app.th3 = app.th3EditField_2.Value;
            app.th4 = app.th4EditField_2.Value;
            app.th5 = app.th5EditField_2.Value;

            app.T_matrix_calc();


        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
           
            app.go_home();
            
            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;
        
            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;
           
            app.th1EditField_2.Value = 0;
            app.th2EditField_2.Value = 0;
            app.th3EditField_2.Value = 0;
            app.th4EditField_2.Value = 0;
            app.th5EditField_2.Value = 0;

            app.th1 = app.th1EditField_2.Value;
            app.th2 = app.th2EditField_2.Value;
            app.th3 = app.th3EditField_2.Value;
            app.th4 = app.th4EditField_2.Value;
            app.th5 = app.th5EditField_2.Value;

            app.T_matrix_calc();
            
            app.SleepButton.Visible ="on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";
            
            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

          
            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

        
            app.ShowthevaluesLabel.Visible = "on";
            app.TargetsLabel.Visible = "on";

            app.th1EditField_2Label.Visible="on";
            app.th1EditField_2.Visible ="on";
        
            app.th2EditField_2Label.Visible="on";
            app.th2EditField_2.Visible="on";

            app.th3EditField_2Label.Visible="on";
            app.th3EditField_2.Visible="on";

            app.th4EditField_2Label.Visible="on";
            app.th4EditField_2.Visible="on";

            app.th5EditField_2Label.Visible="on";
            app.th5EditField_2.Visible="on";
            
            app.MovetoTargetButton.Visible = "on";
            app.ComputeInverseButton.Visible = "on";
            app.TransformationMatrixLabel.Visible = "on";
            app.UITable.Visible = "on";
            app.InverseKinematicsTableLabel.Visible = "on";
            app.MovetosolutionEditField.Visible = "on";
            app.MovetosolutionEditFieldLabel.Visible= "on";
            
           
        end

        % Value changing function: th1Slider
        function th1SliderValueChanging(app, event)
            'Slider 1 is being moved'
            
            
            app.sth1 =  event.Value;
            app.rth1 = app.sth1;

            % while(app.sserror > 0.3 || flag == 0)
            % 
            % flag = 1;
            % app.close_loop_control();
            % 
            % end

              flag =0;
           % while(app.sserror > 0.6 || flag == 0)
           %     % time = time + 1
           % 
           %     flag = 1;
           %     app.close_loop_control();
           % 
           %     % erroar = app.sserror
           %     % if (time > 5)
           %     %      break;
           %     % end
           % 
           % end
          'Out of error loop'

            app.th1EditField.Value = event.Value;
            %app.drawRobot();

            app.T_matrix_calc();
        end

        % Value changing function: th2Slider
        function th2SliderValueChanging(app, event)
            'Slider 2 is being moved'
            

             app.sth2 = event.Value;
             app.rth2 = -app.sth2;
            
            % while(app.sserror > 0.3 || flag == 0)
            % 
            % flag = 1;
            % app.close_loop_control();
            % 
            % end
              flag =0;
           % while(app.sserror > 0.6 || flag == 0)
           %     % time = time + 1
           % 
           %     flag = 1;
           %     app.close_loop_control();
           % 
           %     % erroar = app.sserror
           %     % if (time > 5)
           %     %      break;
           %     % end
           % 
           % end
          'Out of error loop'

            app.th2EditField.Value = event.Value;
            %app.drawRobot();

            app.T_matrix_calc();
            
        end

        % Value changing function: th3Slider
        function th3SliderValueChanging(app, event)
            'Slider 3 is being moved'
        
            app.sth3 = event.Value;
            app.rth3 = -app.sth3;
            
            % while(app.sserror > 0.3 || flag == 0)
            % 
            % flag = 1;
            % app.close_loop_control();
            % 
            % end

              flag =0;
           % while(app.sserror > 0.6 || flag == 0)
           %     % time = time + 1
           % 
           %     flag = 1;
           %     app.close_loop_control();
           % 
           %     % erroar = app.sserror
           %     % if (time > 5)
           %     %      break;
           %     % end
           % 
           % end
          'Out of error loop'

            app.th3EditField.Value = event.Value;
            %app.drawRobot();
%
            app.T_matrix_calc();
            
        end

        % Value changing function: th4Slider
        function th4SliderValueChanging(app, event)
            'Slider 4 is being moved'
            
            app.sth4 =  event.Value;
            app.rth4 = -app.sth4;

            % while(app.sserror > 0.3 || flag == 0)
            % 
            % flag = 1;
            % app.close_loop_control();
            % 
            % end
              flag =0;
           % while(app.sserror > 0.6 || flag == 0)
           %     % time = time + 1
           % 
           %     flag = 1;
           %     app.close_loop_control();
           % 
           %     % erroar = app.sserror
           %     % if (time > 5)
           %     %      break;
           %     % end
           % 
           % end
          'Out of error loop'

            app.th4EditField.Value = event.Value;
           % app.drawRobot();

            app.T_matrix_calc();
            
        end

        % Value changing function: th5Slider
        function th5SliderValueChanging(app, event)
            'Slider 5 is being moved'
          
            app.sth5 = event.Value;
            app.rth5 = app.sth5;
              flag =0;
           % while(app.sserror > 0.6 || flag == 0)
           %     % time = time + 1
           % 
           %     flag = 1;
           %     app.close_loop_control();
           % 
           %     % erroar = app.sserror
           %     % if (time > 5)
           %     %      break;
           %     % end
           % 
           % end
          'Out of error loop'

            app.th5EditField.Value = event.Value;
           % app.drawRobot();

            app.T_matrix_calc();
        end

        % Value changing function: d6Slider
        function d6SliderValueChanging(app, event)
            app.d6 = event.Value;
            app.d6EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.bot.gripper.open();
            app.d6= 5;
            app.drawRobot();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.bot.gripper.close();
            app.d6=20;
            app.drawRobot();
        end

        % Button pushed function: SleepButton
        function SleepButtonPushed(app, event)
            
            app.go_home();

            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;

            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;

            app.go_sleep();
            
            app.SleepButton.Visible = "off";
            app.OpenButton.Visible="off";
            app.CloseButton.Visible="off";
            app.MovetoTargetButton.Visible="off";


            app.th1Slider.Visible="off";
            app.th1SliderLabel.Visible="off";

            app.th2Slider.Visible="off";
            app.th2SliderLabel.Visible="off";

            app.th3Slider.Visible="off";
            app.th3SliderLabel.Visible="off";

            app.th4SliderLabel.Visible="off";
            app.th4Slider.Visible="off";

            app.th5SliderLabel.Visible="off";
            app.th5Slider.Visible="off";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="off";
            app.th1EditField.Visible ="off";
        
            app.th2EditFieldLabel.Visible="off";
            app.th2EditField.Visible="off";

            app.th3EditFieldLabel.Visible="off";
            app.th3EditField.Visible="off";

            app.th4EditFieldLabel.Visible="off";
            app.th4EditField.Visible="off";

            app.th5EditFieldLabel.Visible="off";
            app.th5EditField.Visible="off";

            app.ShowthevaluesLabel.Visible = "off";
            app.TargetsLabel.Visible = "off";

            app.th1EditField_2Label.Visible="off";
            app.th1EditField_2.Visible ="off";
        
            app.th2EditField_2Label.Visible="off";
            app.th2EditField_2.Visible="off";

            app.th3EditField_2Label.Visible="off";
            app.th3EditField_2.Visible="off";

            app.th4EditField_2Label.Visible="off";
            app.th4EditField_2.Visible="off";

            app.th5EditField_2Label.Visible="off";
            app.th5EditField_2.Visible="off";

            app.MovetoTargetButton.Visible="off";
            app.ComputeInverseButton.Visible = "off";
            app.TransformationMatrixLabel.Visible = "off";
            app.UITable.Visible = "off";
            app.InverseKinematicsTableLabel.Visible = "off";
            app.MovetosolutionEditField.Visible= "off";
            app.MovetosolutionEditFieldLabel.Visible= "off";


        end

        % Button pushed function: MovetoTargetButton
        function MovetoTargetButtonPushed(app, event)
       
            'Moving to target position' 
            app.movementflag = "targetPos"
            app.mth1 = app.th1EditField_2.Value;
            app.mth2 = app.th2EditField_2.Value;
            app.mth3 = app.th3EditField_2.Value;
            app.mth4 = app.th4EditField_2.Value;
            app.mth5 = app.th5EditField_2.Value;
         
            app.move();

            app.th1EditField.Value = app.th1EditField_2.Value;
            app.th2EditField.Value = app.th2EditField_2.Value;
            app.th3EditField.Value = app.th3EditField_2.Value;
            app.th4EditField.Value = app.th4EditField_2.Value;
            app.th5EditField.Value = app.th5EditField_2.Value;

            app.th1Slider.Value = app.th1EditField_2.Value;
            app.th2Slider.Value = app.th2EditField_2.Value;
            app.th3Slider.Value = app.th3EditField_2.Value;
            app.th4Slider.Value  = app.th4EditField_2.Value;
            app.th5Slider.Value = app.th5EditField_2.Value;

            app.th1 = app.th1EditField_2.Value;
            app.th2 = app.th2EditField_2.Value;
            app.th3 = app.th3EditField_2.Value;
            app.th4 = app.th4EditField_2.Value;
            app.th5 = app.th5EditField_2.Value;

            app.T_matrix_calc();
            app.movementflag=""
    
         app.StatusEditField.Value =   'I have arrived the target position'
        
        end

        % Button pushed function: ComputeInverseButton
        function ComputeInverseButtonPushed(app, event)
          'Generating inverse kinematics'
             app.inverse_Kinematics();

             if ( (app.T(1,3) < 1.1 && app.T(1,3) > 0.9) || (app.T(1,3) > -1.1 && app.T(1,3) < -0.9) )
                 'Infinite solutions'
                    for j=1:4
                       s = uistyle('BackgroundColor','yellow');
                       addStyle(app.UITable,s,'row',j)
                    end

                    app.StatusEditField.Value = "Infinite number of solutions possible"

             else
                 app.StatusEditField.Value = ""
             end

             %Determining out of bounds rows
             for j=1:4
                 for k=1:5
                 
                 'In loop'
                  if( app.UITable.Data(j,k) >360)
                      app.UITable.Data(j,k) = app.UITable.Data(j,k) -360;

                  end

                  if(app.mth1 < 0 )
                     app.mth1= -app.UITable.Data(j,1);
                  end
                  if(app.mth5 < 0 )
                      app.mth5 = -app.UITable.Data(j,5);
                  end
                 end  


                 if( abs(app.UITable.Data(j,1))>90 || ...
                       abs(app.UITable.Data(j,2))>90 || ...
                       abs(app.UITable.Data(j,3))>90 ||...
                       abs(app.UITable.Data(j,4))>60 ||...
                       abs(app.UITable.Data(j,5))>120)

                       'Out of bounds'

                       s = uistyle('BackgroundColor','red');
                       addStyle(app.UITable,s,'row',j)
                        
                 else    
                       s = uistyle('BackgroundColor','white');
                       addStyle(app.UITable,s,'row',j)
                 end
             end
                
        end

        % Value changed function: MovetosolutionEditField
        function MovetosolutionEditFieldValueChanged(app, event)
            app.Solution= app.MovetosolutionEditField.Value;
             
          app.StatusEditField.Value = 'Moving to inverse solution'
          j = app.Solution;
           if (j < 4)
               
                  if( abs(app.UITable.Data(j,1))<90 && ...
                   abs(app.UITable.Data(j,2))<90 && ...
                   abs(app.UITable.Data(j,3))<90 && ...
                   abs(app.UITable.Data(j,4))<60 && ...
                   abs(app.UITable.Data(j,5))<120)
                   

                  if (j==1)
                   app.mth1 = -app.UITable.Data(j,1);
                  else
                       app.mth1 = app.UITable.Data(j,1);
                      the1 = app.mth1
                  end

                   app.mth2 = app.UITable.Data(j,2);
                   the2 = app.mth2
                   app.mth3 = app.UITable.Data(j,3);
                   the3 = app.mth3
                  app.mth4 = app.UITable.Data(j,4);
                   the4 = app.mth4

                    if (j==1)
                   app.mth5 = -app.UITable.Data(j,5);
                  else
                       app.mth5 = app.UITable.Data(j,5);
                       the5 = app.mth5
                  end
                 
                   app.StatusEditField.Value =  'Moving to solution position specified'
                   app.move();

                   app.th1EditField.Value = app.UITable.Data(j,1);
                app.th2EditField.Value = app.UITable.Data(j,2);
                app.th3EditField.Value = app.UITable.Data(j,3);
                app.th4EditField.Value = app.UITable.Data(j,4);
                app.th5EditField.Value = app.UITable.Data(j,5);
    
                app.th1Slider.Value = app.UITable.Data(j,1);
                app.th2Slider.Value = app.UITable.Data(j,2);
                app.th3Slider.Value = app.UITable.Data(j,3);
                app.th4Slider.Value  = app.UITable.Data(j,4);
                app.th5Slider.Value = app.UITable.Data(j,5);
    
                app.th1 = app.UITable.Data(j,1);
                app.th2 = app.UITable.Data(j,2);
                app.th3 = app.UITable.Data(j,3);
                app.th4 = app.UITable.Data(j,4);
                app.th5 = app.UITable.Data(j,5);
    
                app.T_matrix_calc();
                app.StatusEditField.Value =  'I have arrived the solution positions'
           
                  else 
                     app.StatusEditField.Value =  'The solution specified is out of bounds'
                    
                end
          

           else
                app.StatusEditField.Value =  'The solution specified does not exist'
           end

        end

        % Value changing function: SpeedSlider
        function SpeedSliderValueChanging(app, event)
            'Speed slider being moved'
            app.speed = event.Value;
            app.CurrentspeedEditField.Value = event.Value;
            
        end

        % Button pushed function: EnergizeButton
        function EnergizeButtonPushed(app, event)
             app.StatusEditField.Value = "Energizing servos"
              % Create the service message with the specified parameters
                msg = rosmessage(app.client);
                msg.CmdType = 'single';
                msg.Name = 'waist';
                msg.Enable = true;
                 % Call the service with the message
                response = call(app.client,msg);
                 % Optionally, display the response
                 disp(response)
                
                msg = rosmessage(app.client);
                msg.CmdType = 'single';
                msg.Name = 'shoulder';
                msg.Enable = true;
                 % Call the service with the message
                response = call(app.client,msg);
                 % Optionally, display the response
                 disp(response)
   
                msg = rosmessage(app.client);
                msg.CmdType = 'single';
                msg.Name = 'elbow';
                msg.Enable = true;
                 % Call the service with the message
                response = call(app.client,msg);
                 % Optionally, display the response
                 disp(response)

                
                msg = rosmessage(app.client);
                msg.CmdType = 'single';
                msg.Name = 'wrist_angle';
                msg.Enable = true;
                 % Call the service with the message
                response = call(app.client,msg);
                 % Optionally, display the response
                 disp(response)

                
                msg = rosmessage(app.client);
                msg.CmdType = 'single';
                msg.Name = 'wrist_rotate';
                msg.Enable = true;
                 % Call the service with the message
                response = call(app.client,msg);
                 % Optionally, display the response
                disp(response)


                % msg.CmdType = 'single';
                % msg.Name = 'gripper';
                % msg.Enable = true;
               

        end

        % Button pushed function: RelaxButton
        function RelaxButtonPushed(app, event)
             app.StatusEditField.Value = "Relaxing servos"
              % Create the service message with the specified parameters
                msg = rosmessage(app.client);
                msg.CmdType = 'group';
                msg.Name = 'all';
                msg.Enable = false;
                
                % Call the service with the message
                response = call(app.client,msg);
                
                % Optionally, display the response
                disp(response);

        end

        % Button pushed function: BackgroundSimButton
        function BackgroundSimButtonPushed(app, event)
            app.backgroundFunction()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.StatusEditField.Value = "Closing application"
            app.EnergizeButtonPushed()
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Visible = 'off';
            app.HomeButton.Text = 'Home';
            app.th1SliderLabel = uilabel(app.UIFigure);
            app.th1SliderLabel.Visible = 'off';
            app.th1SliderLabel.Text = 'th1';
            app.th1Slider = uislider(app.UIFigure);
            app.th1Slider.Limits = [-90 90];
            app.th1Slider.ValueChangingFcn = createCallbackFcn(app, @th1SliderValueChanging, true);
            app.th1Slider.Visible = 'off';
            app.th3SliderLabel = uilabel(app.UIFigure);
            app.th3SliderLabel.Visible = 'off';
            app.th3SliderLabel.Text = 'th3';
            app.th3Slider = uislider(app.UIFigure);
            app.th3Slider.Limits = [-90 90];
            app.th3Slider.ValueChangingFcn = createCallbackFcn(app, @th3SliderValueChanging, true);
            app.th3Slider.Visible = 'off';
            app.th2SliderLabel = uilabel(app.UIFigure);
            app.th2SliderLabel.Visible = 'off';
            app.th2SliderLabel.Text = 'th2';
            app.th2Slider = uislider(app.UIFigure);
            app.th2Slider.Limits = [-90 90];
            app.th2Slider.ValueChangingFcn = createCallbackFcn(app, @th2SliderValueChanging, true);
            app.th2Slider.Visible = 'off';
            app.th4SliderLabel = uilabel(app.UIFigure);
            app.th4SliderLabel.Visible = 'off';
            app.th4SliderLabel.Text = 'th4';
            app.th4Slider = uislider(app.UIFigure);
            app.th4Slider.Limits = [-60 60];
            app.th4Slider.ValueChangingFcn = createCallbackFcn(app, @th4SliderValueChanging, true);
            app.th4Slider.Visible = 'off';
            app.th5SliderLabel = uilabel(app.UIFigure);
            app.th5SliderLabel.Visible = 'off';
            app.th5SliderLabel.Text = 'th5';
            app.th5Slider = uislider(app.UIFigure);
            app.th5Slider.Limits = [-120 120];
            app.th5Slider.ValueChangingFcn = createCallbackFcn(app, @th5SliderValueChanging, true);
            app.th5Slider.Visible = 'off';
            app.d6SliderLabel = uilabel(app.UIFigure);
            app.d6SliderLabel.Visible = 'off';
            app.d6SliderLabel.Text = 'd6';
            app.d6Slider = uislider(app.UIFigure);
            app.d6Slider.Limits = [-360 360];
            app.d6Slider.ValueChangingFcn = createCallbackFcn(app, @d6SliderValueChanging, true);
            app.d6Slider.Visible = 'off';
            app.th1EditFieldLabel = uilabel(app.UIFigure);
            app.th1EditFieldLabel.Visible = 'off';
            app.th1EditFieldLabel.Text = 'th1';
            app.th1EditField = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField.Visible = 'off';
            app.th2EditFieldLabel = uilabel(app.UIFigure);
            app.th2EditFieldLabel.Visible = 'off';
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField.Visible = 'off';
            app.th3EditFieldLabel = uilabel(app.UIFigure);
            app.th3EditFieldLabel.Visible = 'off';
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField.Visible = 'off';
            app.th4EditFieldLabel = uilabel(app.UIFigure);
            app.th4EditFieldLabel.Visible = 'off';
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField.Visible = 'off';
            app.th5EditFieldLabel = uilabel(app.UIFigure);
            app.th5EditFieldLabel.Visible = 'off';
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField.Visible = 'off';
            app.SleepButton = uibutton(app.UIFigure, 'push');
            app.SleepButton.ButtonPushedFcn = createCallbackFcn(app, @SleepButtonPushed, true);
            app.SleepButton.Visible = 'off';
            app.SleepButton.Text = 'Sleep';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            app.ShowthevaluesLabel = uilabel(app.UIFigure);
            app.ShowthevaluesLabel.Visible = 'off';
            app.ShowthevaluesLabel.Text = 'Show the values';
            app.TargetsLabel = uilabel(app.UIFigure);
            app.TargetsLabel.Visible = 'off';
            app.TargetsLabel.Text = 'Targets';
            app.th4EditField_2Label = uilabel(app.UIFigure);
            app.th4EditField_2Label.Visible = 'off';
            app.th4EditField_2Label.Text = 'th4';
            app.th4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField_2.Visible = 'off';
            app.th1EditField_2Label = uilabel(app.UIFigure);
            app.th1EditField_2Label.Visible = 'off';
            app.th1EditField_2Label.Text = 'th1';
            app.th1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField_2.Visible = 'off';
            app.th1EditField_2.Tooltip = {''};
            app.th2EditField_2Label = uilabel(app.UIFigure);
            app.th2EditField_2Label.Visible = 'off';
            app.th2EditField_2Label.Text = 'th2';
            app.th2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField_2.Visible = 'off';
            app.th3EditField_2Label = uilabel(app.UIFigure);
            app.th3EditField_2Label.Visible = 'off';
            app.th3EditField_2Label.Text = 'th3';
            app.th3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField_2.Visible = 'off';
            app.th5EditField_2Label = uilabel(app.UIFigure);
            app.th5EditField_2Label.Visible = 'off';
            app.th5EditField_2Label.Text = 'th5';
            app.th5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField_2.Visible = 'off';
            app.MovetoTargetButton = uibutton(app.UIFigure, 'push');
            app.MovetoTargetButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoTargetButtonPushed, true);
            app.MovetoTargetButton.Visible = 'off';
            app.MovetoTargetButton.Text = 'Move to Target Button';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'th1'; 'th2'; 'th3'; 'th4'; 'th5'};
            app.UITable.RowName = {};
            app.UITable.Visible = 'off';
            app.MovetosolutionEditFieldLabel = uilabel(app.UIFigure);
            app.MovetosolutionEditFieldLabel.Visible = 'off';
            app.MovetosolutionEditFieldLabel.Text = 'Move to  solution';
            app.MovetosolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.MovetosolutionEditField.ValueChangedFcn = createCallbackFcn(app, @MovetosolutionEditFieldValueChanged, true);
            app.MovetosolutionEditField.Visible = 'off';
            app.InverseKinematicsTableLabel = uilabel(app.UIFigure);
            app.InverseKinematicsTableLabel.Visible = 'off';
            app.InverseKinematicsTableLabel.Text = 'Inverse Kinematics Table';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'Z'; 'P'};
            app.UITable_2.RowName = {};
            app.TransformationMatrixLabel = uilabel(app.UIFigure);
            app.TransformationMatrixLabel.Text = 'Transformation Matrix';
            app.ComputeInverseButton = uibutton(app.UIFigure, 'push');
            app.ComputeInverseButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeInverseButtonPushed, true);
            app.ComputeInverseButton.Visible = 'off';
            app.ComputeInverseButton.Text = 'Compute Inverse';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.SpeedSliderLabel = uilabel(app.UIFigure);
            app.SpeedSliderLabel.Visible = 'off';
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.UIFigure);
            app.SpeedSlider.Limits = [1 100];
            app.SpeedSlider.ValueChangingFcn = createCallbackFcn(app, @SpeedSliderValueChanging, true);
            app.SpeedSlider.Visible = 'off';
            app.SpeedSlider.Value = 1;
            app.HighLabel = uilabel(app.UIFigure);
            app.HighLabel.Visible = 'off';
            app.HighLabel.Text = 'High';
            app.LowLabel = uilabel(app.UIFigure);
            app.LowLabel.Visible = 'off';
            app.LowLabel.Text = 'Low';
            app.CurrentspeedEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentspeedEditFieldLabel.Visible = 'off';
            app.CurrentspeedEditFieldLabel.Text = 'Current speed';
            app.CurrentspeedEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentspeedEditField.Visible = 'off';
            app.NoteMoreaccuratemovesatlowerspeedsLabel = uilabel(app.UIFigure);
            app.NoteMoreaccuratemovesatlowerspeedsLabel.Visible = 'off';
            app.NoteMoreaccuratemovesatlowerspeedsLabel.Text = 'Note: More accurate moves at lower speeds';
            app.EnergizeButton = uibutton(app.UIFigure, 'push');
            app.EnergizeButton.ButtonPushedFcn = createCallbackFcn(app, @EnergizeButtonPushed, true);
            app.EnergizeButton.Text = 'Energize';
            app.RelaxButton = uibutton(app.UIFigure, 'push');
            app.RelaxButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxButtonPushed, true);
            app.RelaxButton.Text = 'Relax';
            app.BackgroundSimButton = uibutton(app.UIFigure, 'push');
            app.BackgroundSimButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundSimButtonPushed, true);
            app.BackgroundSimButton.Text = 'BackgroundSim';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end