classdef HW7_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ComputeInverseButton          matlab.ui.control.Button
        TransformationMatrixLabel     matlab.ui.control.Label
        UITable_2                     matlab.ui.control.Table
        InverseKinematicsTableLabel   matlab.ui.control.Label
        MovetosolutionEditField       matlab.ui.control.NumericEditField
        MovetosolutionEditFieldLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        MovetoTargetButton            matlab.ui.control.Button
        th5EditField_2                matlab.ui.control.NumericEditField
        th5EditField_2Label           matlab.ui.control.Label
        th3EditField_2                matlab.ui.control.NumericEditField
        th3EditField_2Label           matlab.ui.control.Label
        th2EditField_2                matlab.ui.control.NumericEditField
        th2EditField_2Label           matlab.ui.control.Label
        th1EditField_2                matlab.ui.control.NumericEditField
        th1EditField_2Label           matlab.ui.control.Label
        th4EditField_2                matlab.ui.control.NumericEditField
        th4EditField_2Label           matlab.ui.control.Label
        TargetsLabel                  matlab.ui.control.Label
        ShowthevaluesLabel            matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        SleepButton                   matlab.ui.control.Button
        th5EditField                  matlab.ui.control.NumericEditField
        th5EditFieldLabel             matlab.ui.control.Label
        th4EditField                  matlab.ui.control.NumericEditField
        th4EditFieldLabel             matlab.ui.control.Label
        th3EditField                  matlab.ui.control.NumericEditField
        th3EditFieldLabel             matlab.ui.control.Label
        th2EditField                  matlab.ui.control.NumericEditField
        th2EditFieldLabel             matlab.ui.control.Label
        th1EditField                  matlab.ui.control.NumericEditField
        th1EditFieldLabel             matlab.ui.control.Label
        d6Slider                      matlab.ui.control.Slider
        d6SliderLabel                 matlab.ui.control.Label
        th5Slider                     matlab.ui.control.Slider
        th5SliderLabel                matlab.ui.control.Label
        th4Slider                     matlab.ui.control.Slider
        th4SliderLabel                matlab.ui.control.Label
        th2Slider                     matlab.ui.control.Slider
        th2SliderLabel                matlab.ui.control.Label
        th3Slider                     matlab.ui.control.Slider
        th3SliderLabel                matlab.ui.control.Label
        th1Slider                     matlab.ui.control.Slider
        th1SliderLabel                matlab.ui.control.Label
        HomeButton                    matlab.ui.control.Button
        InitializeButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description

        bot;

        Fb;
        Vb;

        F1;
        V1;

        F2;
        V2;

        F3;
        V3;

        F4;
        V4;

        F5;
        V5;

        F6;
        V6;

        F7;
        V7;
        
        T;

        L1 = 193;
        L2 = 196;
        L3 = 69.5;

        th1 = 0;
        th2 = 0;
        th3 = 0;
        th4 = 0;
        th5 = 0;
        d6 = 0;

        rth1 = 0;
        rth2 = 0;
        rth3 = 0;
        rth4 = 0;
        rth5 = 0;
        rd6 = 0;

        error=0;
        sserror=1;

    end
    
    methods (Access = private)
        
        function [] = drawRobot(app)
            
             %ah is alpha
            cla
            plot3(0,0,0,'r*','MarkerSize',10)

          
            %Link base
            %AH = ALPHA;
            pb = app.Vb;

            fcs =['g'];
            patch('faces',app.Fb, 'vertices',pb(1:3,:)','facecolor',fcs)

            %Link1
            
            c = cosd(app.th1); s=sind(app.th1);

            T1A = [c -s 0 0;
                   s  c  0 0;
                   0  0  1 0;
                   0  0  0  1]; 

            c = cosd(app.th2 - 77); s=sind(app.th2 - 77);
            T21 = [c -s 0  0;
                   0  0  1 0;
                   -s -c 0 0;
                   0   0  0 1];

             c = cosd(app.th3 + 79); s=sind(app.th3 + 79);
             T32 = [c -s 0 193;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0  1];

             c = cosd(app.th4 - 88); s=sind(app.th4 - 88 );
             T43 = [c -s 0 196;
                    s  c 0  0;
                    0  0 1 0;
                    0  0 0 1];

              c = cosd(app.th5); s=sind(app.th5);
              T54 = [c -s 0 -16;
                     0  0 1 67.5;
                     -s -c 0 0;
                     0   0 0 1];

            T20 = T1A * T21;
            T30 = T20 * T32;
            T40 = T30 * T43;
            T50 = T40 * T54;

            p1 = T1A*app.V1;
            fcs =['r'];
            patch('faces',app.F1, 'vertices', p1(1:3,:)','facecolor',fcs)

             p2 = T20*app.V2;
             fcs =['c'];
             patch('faces',app.F2, 'vertices',p2(1:3,:)','facecolor',fcs)

             p3 = T30*app.V3;
             fcs =['b']; 
             patch('faces',app.F3, 'vertices',p3(1:3,:)','facecolor',fcs)


             %Link4  

             p4 = T40*app.V4;
             fcs =['y']; 
             patch('faces',app.F4, 'vertices',p4(1:3,:)','facecolor',fcs)

             %Link5 
      
             p5 = T50*app.V5;
             fcs =['m']; 
             patch('faces',app.F5, 'vertices',p5(1:3,:)','facecolor',fcs)

             %Link6
             T65 = eye(4);
             Trot = eye(4);
             Trot(1:3,4) = [0 -(app.d6 - 19.75)  0 ]';
           
             T65 = T65*Trot;

             p6 = T50*T65*app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F6, 'vertices',p6(1:3,:)','facecolor',fcs)

             %Link7
             Trot = eye(4);
             Trot(1:3,1:3)=rotz(180);
             Trot(1:3,4) = [15 +(app.d6 - 19.75)  0]';
             T76 = Trot*T65;

             p7 = T50 * T76 *app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F7, 'vertices',p7(1:3,:)','facecolor',fcs)
             
        end
        
        function []= close_loop_control(app)
            joint_positions=[app.rth1 -1*app.rth2 -1*app.rth3 -1*(app.rth4) app.rth5]*pi/180;
            app.bot.arm.set_joint_positions(joint_positions);
            app.bot.arm.capture_joint_positions();
            app.error=app.bot.arm.joint_commands'- joint_positions;
            app.sserror=dot(app.error,app.error); 
         
        end
        
        function [] = go_home(app)
              
            % Define the target joint angles for the home position
    home_joint_angles = [0, 0, 0, 0, 0]; % Adjust as needed
    
    % Define the number of steps for interpolation
    num_steps = 50; % Adjust as needed
    
    % Generate a trajectory of joint angles using linear interpolation
    joint_angles_trajectory = zeros(num_steps, 5);
    for i = 1:num_steps
        joint_angles_trajectory(i, :) = app.th1 + (home_joint_angles - app.th1) * i / num_steps;
    end
    
    % Move the robot along the trajectory while updating the drawing
    for i = 1:num_steps
        app.th1 = joint_angles_trajectory(i, 1);
        app.th2 = joint_angles_trajectory(i, 2);
        app.th3 = joint_angles_trajectory(i, 3);
        app.th4 = joint_angles_trajectory(i, 4);
        app.th5 = joint_angles_trajectory(i, 5);

        app.th1 = -app.rth1;
        app.th2 = -app.rth2;
        app.th3 = -app.rth3;
        app.th4 = -app.rth4;
        app.th5 = -app.rth5;
        
        joint_positions=[app.rth1 -1*app.rth2 -1*app.rth3 -1*(app.rth4) app.rth5]*pi/180;
        app.bot.arm.set_joint_positions(joint_positions);
        % Update the drawing of the robot
        app.drawRobot();
        
        % Update GUI elements if necessary
        app.th1EditField.Value = app.th1;
        app.th2EditField.Value = app.th2;
        app.th3EditField.Value = app.th3;
        app.th4EditField.Value = app.th4;
        app.th5EditField.Value = app.th5;
        
        % Pause for smooth motion (adjust as needed)
        pause(0.1); % Adjust the pause duration for desired speed
    end
    
    % Display a message when the robot reaches the home position
    disp('Robot has reached the home position');
        end


        function [] = go_sleep(app)
            'Moving to sleep position'
            while( (app.th1 < -1 || app.th1 > 1) || (app.th2 < -99 || app.th2 > -97)  || (app.th3 < 89 || app.th3 > 91) || (app.th4 < 43 || app.th4 > 45)  || (app.th5 < -1 || app.th5 > 1)   )
                    
                    'Th1'
                    if(app.th1 < -20)
                        app.th1 = app.th1 + 5;
                        app.rth1=-app.th1;

                    elseif(app.th1 > 20)
                        app.th1 = app.th1 - 5;
                        app.rth1=-app.th1;
                    end

                    if(app.th1 < -1 && app.th1 > -20)
                        app.th1 = (app.th1 + 0.3);
                        app.rth1=-app.th1;
                        
                    elseif(app.th1 > 1 && app.th1 < 20)
                        app.th1 = (app.th1 - 0.3);
                        app.rth1=-app.th1;
                      
                    end


                    'Th2'
                    if(app.th2 < -99 -20)
                        app.th2 = app.th2 + 5;
                        app.rth2=-app.th2;

                    elseif(app.th2 > -97 + 20)
                        app.th2 = app.th2 - 5;
                        app.rth2=-app.th2;
                    end
                
                    if(app.th2 < -99 && app.th2  > -99-20)
                        app.th2 = (app.th2 + 0.3);
                        app.rth2=-app.th2;
                      
                    elseif(app.th2 > -97 && app.th2 < -97 + 20)
                        app.th2 = (app.th2 - 0.3);
                        app.rth2=-app.th2;
                   
                    end
                    
                    'Th3'
                    if(app.th3 < 89 - 20)
                        app.th3 = app.th3 + 5;
                        app.rth3=-app.th3;

                    elseif(app.th3 > 91 + 20)
                        app.th3 = app.th3 - 5;
                        app.rth3=-app.th3;
                    end
                    if(app.th3 <89 && app.th3 > 89-20)
                        app.th3 = (app.th3 + 0.3);
                        app.rth3=-app.th3;
                   
                    elseif(app.th3 > 91 && app.th3 < 91 + 20)
                        app.th3 = (app.th3 - 0.3);
                         app.rth3=-app.th3;
                  
                    end
                    
                    'Th4'
                    if(app.th4 < 43 - 20)
                        app.th4 = app.th4 + 5;
                        app.rth4=-app.th4;

                    elseif(app.th4 > 45 + 20)
                        app.th4 = app.th4 - 5;
                        app.rth4=-app.th4;
                    end
                    if(app.th4 < 43)
                        app.th4 = (app.th4 + 0.3);
                         app.rth4=-app.th4;
                     
                    elseif(app.th4 > 45)
                        app.th4 = (app.th4 - 0.3);
                        app.rth4=-app.th4;
                     
                    end

                    'Th5'
                    if(app.th5 < -20)
                        app.th5 = app.th5 + 5;
                        app.rth5=-app.th5;

                    elseif(app.th5 > 20)
                        app.th5 = app.th5 - 5;
                        app.rth5=-app.th5;
                    end
              
                    if(app.th5 < -1)
                        app.th5 = (app.th5 + 0.3);
                        app.rth5=-app.th5;
          
                    elseif(app.th5 > 1)
                        app.th5 = (app.th5 - 0.3);
                        app.rth5=-app.th5;
                
                    end

                   flag = 0;
                   time = 0
                   while(app.sserror > 0.6 || flag == 0)
                       time = time + 1
    
                       flag = 1;
                       app.close_loop_control();
    
                       erroar = app.sserror
                       if (time > 5)
                            break;
                       end
    
                   end
                   
                   'Out of error loop'
                          
                   'Drawing robot'
                   app.drawRobot()
                   pause(0.05)
  
                   app.th1EditField.Value = app.th1;
                   app.th2EditField.Value = app.th2;
                   app.th3EditField.Value = app.th3;
                   app.th4EditField.Value = app.th4;
                   app.th5EditField.Value = app.th5;
               
            end
            
            'I am now at the sleep position'
        end
               
        
        function [] = inverse_Kinematics(app)
   
            'Generating Inverse Kinematics'
            Tin = app.T; 
            px = Tin(1,4); %cosd(theta1) * [L2*cosd(theta2+theta3)+L1*cosd(theta2)-L3*sind(theta2+theta3+theta4)]
            
            py = Tin(2,4); %sind(theta1) * [L2*cosd(theta2+theta3)+L1*cosd(theta2)-L3*sind(theta2+theta3+theta4)]
            pz=Tin(3,4);
            
            temp = double(atan2d(py,px));
            theta(1,:) = [temp, temp, temp+180, temp+180]; %theta1 known
            
            if abs(cosd(theta(1,1))) > 0.1
                s234 = -Tin(1,3)./cosd(theta(1,:));
            else
                s234 = -Tin(2,3)./sind(theta(1,:));
            end
            
            temp= -Tin(3,3);
            c234 = [temp temp temp temp];
            theta234 = atan2d(s234,c234); %theta234 known

            theta234(theta234<0) = theta234(theta234<0) + 360;
       

            if abs(s234) < 1e-6

                if c234 > 0

                    c1m5 = atan2d(Tin(1,2),Tin(1,1));
                    theta(5,:) = theta(1,:) - c1m5;
                
                else
                    c1p5 = atan2d(Tin(1,2),-1*Tin(1,1));
                    theta(5,:) = c1p5 - theta(1,:);

                end
                
            else   
                 theta(5,:) = atan2d(Tin(3,2)./s234,-Tin(3,1)./s234); %theta5 known
            end


            if abs(cosd(theta(1,1))) > 0.1
                A = double(px./cosd(theta(1,:)) + app.L3*s234);
            else
                A = double(py./sind(theta(1,:)) + app.L3*s234);
            end
            
            B = double(-app.L3*c234 - pz);
            
            a = double(2*A*app.L1);
            b = double(2*B*app.L1);
            c = double(A.^2 + B.^2+app.L1^2-app.L2^2);
            
            m = [1 -1 1 -1];

   
            rad = a.^2+b.^2-c.^2;
            if abs(rad) < 1.0e-3
                rad = 0;     
            end
            theta(2,:)= atan2d(b,a) + m.*atan2d(sqrt(rad),c); %theta2 known;


            theta23 = atan2d(B-app.L1*sind(theta(2,:)), A-app.L1*cosd(theta(2,:)));
            
            theta(3,:)= theta23 - theta(2,:) ;%theta3 known;
            theta(4,:) = theta234 - theta23; %theta4 known;
    
             for j=1:4
                        thc1 = theta(1,j);
                        thc2 = theta(2,j);
                        thc3 = theta(3,j);
                        thc4 = theta(4,j);
                        thc5 = theta(5,j);
                       % Tcheck =  double(subs(T, [theta1 theta2 theta3 theta4 theta5], theta(:,j)'));
                       Tcheck =  [sind(thc1)*sind(thc5) + cosd(thc2 + thc3 + thc4)*cosd(thc1)*cosd(thc5),   cosd(thc5)*sind(thc1) - cosd(thc2 + thc3 + thc4)*cosd(thc1)*sind(thc5), -sind(thc2 + thc3 + thc4)*cosd(thc1), cosd(thc1)*(app.L2*cosd(thc2 + thc3) + app.L1*cosd(thc2) - app.L3*sind(thc2 + thc3 + thc4));
                               cosd(thc2 + thc3 + thc4)*cosd(thc5)*sind(thc1) - cosd(thc1)*sind(thc5), - cosd(thc1)*cosd(thc5) - cosd(thc2 + thc3 + thc4)*sind(thc1)*sind(thc5), -sind(thc2 + thc3 + thc4)*sind(thc1), sind(thc1)*(app.L2*cosd(thc2 + thc3) + app.L1*cosd(thc2) - app.L3*sind(thc2 + thc3 + thc4));
                            -sind(thc2 + thc3 + thc4)*cosd(thc5),                                sind(thc2 + thc3 + thc4)*sind(thc5),          -cosd(thc2 + thc3 + thc4),          - app.L2*sind(thc2 + thc3) - app.L1*sind(thc2) - app.L3*cosd(thc2 + thc3 + thc4);
                                                        0,                                                            0,                              0,                                                                    1];
                        Tdif = Tin-Tcheck;
                        Tdif(Tdif < 1e-5) = 0;

                        if (max(max(Tdif)) > 1e-6)
                            Tdif 
                            'Error in inverse kinenatics'
                        end
         
                       
              end

            
            app.UITable.Data = theta';
            
            
        end
        
        function [] = T_matrix_calc(app)
            'Generating Transformation Matrix'
            app.T = [sind(app.th1)*sind(app.th5) + cosd(app.th2 + app.th3 + app.th4)*cosd(app.th1)*cosd(app.th5),   cosd(app.th5)*sind(app.th1) - cosd(app.th2 + app.th3 + app.th4)*cosd(app.th1)*sind(app.th5), -sind(app.th2 + app.th3 + app.th4)*cosd(app.th1), cosd(app.th1)*(app.L2*cosd(app.th2 + app.th3) + app.L1*cosd(app.th2) - app.L3*sind(app.th2 + app.th3 + app.th4));
                   cosd(app.th2 + app.th3 + app.th4)*cosd(app.th5)*sind(app.th1) - cosd(app.th1)*sind(app.th5), - cosd(app.th1)*cosd(app.th5) - cosd(app.th2 + app.th3 + app.th4)*sind(app.th1)*sind(app.th5), -sind(app.th2 + app.th3 + app.th4)*sind(app.th1), sind(app.th1)*(app.L2*cosd(app.th2 + app.th3) + app.L1*cosd(app.th2) - app.L3*sind(app.th2 + app.th3 + app.th4));
                            -sind(app.th2 + app.th3 + app.th4)*cosd(app.th5),                                sind(app.th2 + app.th3 + app.th4)*sind(app.th5),          -cosd(app.th2 + app.th3 + app.th4),          - app.L2*sind(app.th2 + app.th3) - app.L1*sind(app.th2) - app.L3*cosd(app.th2 + app.th3 + app.th4);
                                                        0,                                                            0,                              0,                                                                    1];
             app.UITable_2.Data = app.T;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            rosshutdown 

            close all
            plot3(0,0,0)
            s = 600;
            axis ([-s s -s s -s s ])
            grid on
            xlabel('x')
            ylabel('y')
            zlabel('z')
            axis square
            hold on
            cla

            [app.Fb, app.Vb] = stlread_mod("Base.STL");
            [app.F1, app.V1] = stlread_mod("Link1.STL");
            [app.F2, app.V2] = stlread_mod("Link2.STL");
            [app.F3, app.V3] = stlread_mod("Link3.STL");
            [app.F4, app.V4] = stlread_mod("Link4.STL");
            [app.F5, app.V5] = stlread_mod("Link5.STL");
            [app.F6, app.V6] = stlread_mod("Link6.STL");
            [app.F7, app.V7] = stlread_mod("Link7.STL");
            
            %Default position due to coordinate systems
            app.drawRobot()
            
            app.InitializeButton.Visible="off";
            app.HomeButton.Visible="on";
            app.SleepButton.Visible = "on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";


            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

            app.ShowthevaluesLabel.Visible = "on";
            app.TargetsLabel.Visible = "on";

            app.th1EditField_2Label.Visible="on";
            app.th1EditField_2.Visible ="on";
        
            app.th2EditField_2Label.Visible="on";
            app.th2EditField_2.Visible="on";

            app.th3EditField_2Label.Visible="on";
            app.th3EditField_2.Visible="on";

            app.th4EditField_2Label.Visible="on";
            app.th4EditField_2.Visible="on";

            app.th5EditField_2Label.Visible="on";
            app.th5EditField_2.Visible="on";

            app.MovetoTargetButton.Visible = "on";

            app.bot = InterbotixManipulatorXS("rx200", "arm", "gripper");
            
            %Very initial position
            app.bot.arm.capture_joint_positions();
            initial_pos = app.bot.arm.joint_commands' * 180/pi
            
            app.th1 = initial_pos(1,1);
            app.th2 = initial_pos(1,2);
            app.th3 = initial_pos(1,3);
            app.th4 = initial_pos(1,4);
            app.th5 = initial_pos(1,5);
      
            app.bot.gripper.open();
            app.d6= 5;

            app.drawRobot()

            app.go_home();
            app.th1EditField.Value = 0;
           app.th2EditField.Value = 0;
           app.th3EditField.Value = 0;
           app.th4EditField.Value = 0;
           app.th5EditField.Value = 0;

           app.th1Slider.Value = 0;
           app.th2Slider.Value = 0;
           app.th3Slider.Value = 0;
           app.th4Slider.Value  = 0;
           app.th5Slider.Value = 0;

           app.th1EditField_2.Value = 0;
           app.th2EditField_2.Value = 0;
           app.th3EditField_2.Value = 0;
           app.th4EditField_2.Value = 0;
           app.th5EditField_2.Value = 0;

        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
           
            app.go_home();
            
            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;
        
            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;
           
            app.th1EditField_2.Value = 0;
            app.th2EditField_2.Value = 0;
            app.th3EditField_2.Value = 0;
            app.th4EditField_2.Value = 0;
            app.th5EditField_2.Value = 0;
            
            app.SleepButton.Visible ="on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";
            
            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

          
            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

        
            app.ShowthevaluesLabel.Visible = "on";
            app.TargetsLabel.Visible = "on";

            app.th1EditField_2Label.Visible="on";
            app.th1EditField_2.Visible ="on";
        
            app.th2EditField_2Label.Visible="on";
            app.th2EditField_2.Visible="on";

            app.th3EditField_2Label.Visible="on";
            app.th3EditField_2.Visible="on";

            app.th4EditField_2Label.Visible="on";
            app.th4EditField_2.Visible="on";

            app.th5EditField_2Label.Visible="on";
            app.th5EditField_2.Visible="on";
            
            app.MovetoTargetButton.Visible = "on";
           
        end

        % Value changing function: th1Slider
        function th1SliderValueChanging(app, event)
            'Slider 1 is being moved'
            flag = 0;
            
            app.th1 = event.Value;
            app.rth1 = app.th1

            while(app.sserror > 0.3 || flag == 0)
           
            flag = 1;
            app.close_loop_control();
  
            end

            app.th1EditField.Value = event.Value;
            app.drawRobot();
        end

        % Value changing function: th2Slider
        function th2SliderValueChanging(app, event)
            'Slider 2 is being moved'
            flag = 0;

             app.th2 = -1*event.Value;
             app.rth2 = -app.th2;
            
            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();

            end

            app.th2EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th3Slider
        function th3SliderValueChanging(app, event)
            'Slider 3 is being moved'
            flag = 0;
            app.th3 = event.Value;
            app.rth3 = -app.th3;
            
            while(app.sserror > 0.3 || flag == 0)
           
            flag = 1;
            app.close_loop_control();
  
            end

            app.th3EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th4Slider
        function th4SliderValueChanging(app, event)
            'Slider 4 is being moved'
            flag = 0;
            app.th4 =  event.Value;
            app.rth4 = -app.th4;

            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();
  
            end

            app.th4EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th5Slider
        function th5SliderValueChanging(app, event)
            'Slider 5 is being moved'
            flag = 0;
            app.th5 = event.Value;
            app.rth5 = app.th5;
            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();
  
            end

            app.th5EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: d6Slider
        function d6SliderValueChanging(app, event)
            app.d6 = event.Value;
            app.d6EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.bot.gripper.open();
            app.d6= 5;
            app.drawRobot();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.bot.gripper.close();
            app.d6=20;
            app.drawRobot();
        end

        % Button pushed function: SleepButton
        function SleepButtonPushed(app, event)
            
            app.go_home();

            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;

            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;

            app.go_sleep();
            
            app.SleepButton.Visible = "off";
            app.OpenButton.Visible="off";
            app.CloseButton.Visible="off";
            app.MovetoTargetButton.Visible="off";


            app.th1Slider.Visible="off";
            app.th1SliderLabel.Visible="off";

            app.th2Slider.Visible="off";
            app.th2SliderLabel.Visible="off";

            app.th3Slider.Visible="off";
            app.th3SliderLabel.Visible="off";

            app.th4SliderLabel.Visible="off";
            app.th4Slider.Visible="off";

            app.th5SliderLabel.Visible="off";
            app.th5Slider.Visible="off";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="off";
            app.th1EditField.Visible ="off";
        
            app.th2EditFieldLabel.Visible="off";
            app.th2EditField.Visible="off";

            app.th3EditFieldLabel.Visible="off";
            app.th3EditField.Visible="off";

            app.th4EditFieldLabel.Visible="off";
            app.th4EditField.Visible="off";

            app.th5EditFieldLabel.Visible="off";
            app.th5EditField.Visible="off";

            app.ShowthevaluesLabel.Visible = "off";
            app.TargetsLabel.Visible = "off";

            app.th1EditField_2Label.Visible="off";
            app.th1EditField_2.Visible ="off";
        
            app.th2EditField_2Label.Visible="off";
            app.th2EditField_2.Visible="off";

            app.th3EditField_2Label.Visible="off";
            app.th3EditField_2.Visible="off";

            app.th4EditField_2Label.Visible="off";
            app.th4EditField_2.Visible="off";

            app.th5EditField_2Label.Visible="off";
            app.th5EditField_2.Visible="off";

            app.MovetoTargetButton.Visible="off";


        end

        % Button pushed function: MovetoTargetButton
        function MovetoTargetButtonPushed(app, event)
            
            'Moving to target position' 
            mth1 = app.th1EditField_2.Value;
            mth2 = -app.th2EditField_2.Value;
            mth3 = -app.th3EditField_2.Value;
            mth4 = -app.th4EditField_2.Value;
            mth5 = app.th5EditField_2.Value;
         
     
             while( (app.th1 < (mth1 -1) || app.th1 > (mth1 +1)) || (app.th2 < (mth2 -1) || app.th2 > (mth2 +1))  || (app.th3 < (mth3 -1) || app.th3 > (mth3 +1)) || (app.th4 < (mth4 -1) || app.th4 > (mth4 +1))  || (app.th5 < (mth5 -1) || app.th5 > (mth5 +1))   )
                    
                    'In a while loop'
                    'Th1'
                    if(app.th1 < mth1 -20)
                        app.th1 = app.th1 + 5;
                        app.rth1=app.th1;

                    elseif(app.th1 > mth1 + 20)
                        app.th1 = app.th1 - 5;
                        app.rth1=app.th1;
                    end

                    if(app.th1 < mth1 -1 && app.th1 > mth1 -20)
                        app.th1 = app.th1 + 0.3;
                        app.rth1= app.th1;

                    elseif(app.th1 > mth1 + 1 && app.th1 < mth1+ 20)
                        app.th1 = app.th1 - 0.3;
                        app.rth1= app.th1;
                    end
                    
                     'Th2'
                    if(app.th2 < mth2 - 20)
                        app.th2 = app.th2 + 5;
                        app.rth2 =-app.th2;

                    elseif(app.th2 > mth2 + 20)
                        app.th2 = app.th2 - 5;
                        app.rth2 =-app.th2;
                    end
                    
                    if(app.th2 < mth2 -1 && app.th2 > mth2 -20)
                         app.th2 = app.th2 + 0.3;
                        app.rth2=-app.th2;

                    elseif(app.th2 > mth2 + 1 && app.th2 < mth2 + 20)
                        app.th2 = app.th2 - 0.3;
                        app.rth2=-app.th2;
                    end
                    
                      'Th3'
                    if(app.th3 < mth3 - 20)
                        app.th3 = app.th3 + 5;
                        app.rth3 =-app.th3;

                    elseif(app.th3 > mth3 + 20)
                        app.th3 = app.th3 - 5;
                        app.rth3 =-app.th3;
                    end
                    
                    if(app.th3 < mth3-1 && app.th3 > mth3-20)
                        app.th3 = app.th3 + 0.3;
                        app.rth3=-app.th3;

                    elseif(app.th3 > mth3+1 && app.th3 < mth3+20 )
                        app.th3 = app.th3 - 0.3;
                        app.rth3=-app.th3;
                    end

                    'Th4'
                    if(app.th4 < mth4-20)
                        app.th4 = app.th4 + 5;
                        app.rth4 =-app.th4;

                    elseif(app.th4 > mth4+20)
                        app.th4 = app.th4 - 5;
                        app.rth4 =-app.th4;
                    end
                    
                    if(app.th4 < mth4-1 && app.th4 > mth4-20)
                        app.th4 = app.th4 + 0.3;
                        app.rth4=-app.th4;

                    elseif(app.th4 > mth4+1 && app.th4 < mth4+20)
                        app.th4 = app.th4 - 0.3;
                        app.rth4=-app.th4;

                    end
                    
                    'Th5'
                    if(app.th5 <mth5 -20)
                        app.th5 = app.th5 + 5;
                        app.rth5 =-app.th5;

                    elseif(app.th5 > mth5+ 20)
                        app.th5 = app.th5 - 5;
                        app.rth5 =-app.th5;

                    end
                    
                    if(app.th5 < mth5-1 && app.th5 > mth5-20)
                        app.th5 = app.th5 + 0.3;
                        app.rth5=-app.th5;

                    elseif(app.th5 > mth5+1 && app.th5 < mth5+20)
                        app.th5 = app.th5 - 0.3;
                        app.rth5=-app.th5;

                    end

                   flag = 0;
                   time = 0
                   while(app.sserror > 0.6 || flag == 0)
                       time = time + 1
    
                       flag = 1;
                       app.close_loop_control();
    
                       erroar = app.sserror
                       if (time > 5)
                            break;
                       end
    
                   end
                   'Out of error loop'
                
                      
               'Drawing robot'
               app.drawRobot()
               pause(0.05)

               app.th1EditField.Value = app.th1;
               app.th2EditField.Value = -app.th2;
               app.th3EditField.Value = -app.th3;
               app.th4EditField.Value = -app.th4;
               app.th5EditField.Value = app.th5;
    
               app.th1Slider.Value = app.th1;
               app.th2Slider.Value = -app.th2;
               app.th3Slider.Value = -app.th3;
               app.th4Slider.Value  = -app.th4;
               app.th5Slider.Value = app.th5;
               
               app.T_matrix_calc();
               
             end
             
            app.th1EditField.Value = app.th1EditField_2.Value;
            app.th2EditField.Value = app.th2EditField_2.Value;
            app.th3EditField.Value = app.th3EditField_2.Value;
            app.th4EditField.Value = app.th4EditField_2.Value;
            app.th5EditField.Value = app.th5EditField_2.Value;

            app.th1Slider.Value = app.th1EditField_2.Value;
            app.th2Slider.Value = app.th2EditField_2.Value;
            app.th3Slider.Value = app.th3EditField_2.Value;
            app.th4Slider.Value  = app.th4EditField_2.Value;
            app.th5Slider.Value = app.th5EditField_2.Value;

            app.th1 = app.th1EditField_2.Value;
            app.th2 = app.th2EditField_2.Value;
            app.th3 = app.th3EditField_2.Value;
            app.th4 = app.th4EditField_2.Value;
            app.th5 = app.th5EditField_2.Value;

            app.T_matrix_calc();

            'I have arrived the target position'
        
        end

        % Button pushed function: ComputeInverseButton
        function ComputeInverseButtonPushed(app, event)
            'Generating inverse kinematics'
             app.inverse_Kinematics();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Visible = 'off';
            app.HomeButton.Text = 'Home';
            app.th1SliderLabel = uilabel(app.UIFigure);
            app.th1SliderLabel.Visible = 'off';
            app.th1SliderLabel.Text = 'th1';
            app.th1Slider = uislider(app.UIFigure);
            app.th1Slider.Limits = [-90 90];
            app.th1Slider.ValueChangingFcn = createCallbackFcn(app, @th1SliderValueChanging, true);
            app.th1Slider.Visible = 'off';
            app.th3SliderLabel = uilabel(app.UIFigure);
            app.th3SliderLabel.Visible = 'off';
            app.th3SliderLabel.Text = 'th3';
            app.th3Slider = uislider(app.UIFigure);
            app.th3Slider.Limits = [-90 90];
            app.th3Slider.ValueChangingFcn = createCallbackFcn(app, @th3SliderValueChanging, true);
            app.th3Slider.Visible = 'off';
            app.th2SliderLabel = uilabel(app.UIFigure);
            app.th2SliderLabel.Visible = 'off';
            app.th2SliderLabel.Text = 'th2';
            app.th2Slider = uislider(app.UIFigure);
            app.th2Slider.Limits = [-90 90];
            app.th2Slider.ValueChangingFcn = createCallbackFcn(app, @th2SliderValueChanging, true);
            app.th2Slider.Visible = 'off';
            app.th4SliderLabel = uilabel(app.UIFigure);
            app.th4SliderLabel.Visible = 'off';
            app.th4SliderLabel.Text = 'th4';
            app.th4Slider = uislider(app.UIFigure);
            app.th4Slider.Limits = [-60 60];
            app.th4Slider.ValueChangingFcn = createCallbackFcn(app, @th4SliderValueChanging, true);
            app.th4Slider.Visible = 'off';
            app.th5SliderLabel = uilabel(app.UIFigure);
            app.th5SliderLabel.Visible = 'off';
            app.th5SliderLabel.Text = 'th5';
            app.th5Slider = uislider(app.UIFigure);
            app.th5Slider.Limits = [-120 120];
            app.th5Slider.ValueChangingFcn = createCallbackFcn(app, @th5SliderValueChanging, true);
            app.th5Slider.Visible = 'off';
            app.d6SliderLabel = uilabel(app.UIFigure);
            app.d6SliderLabel.Visible = 'off';
            app.d6SliderLabel.Text = 'd6';
            app.d6Slider = uislider(app.UIFigure);
            app.d6Slider.Limits = [-360 360];
            app.d6Slider.ValueChangingFcn = createCallbackFcn(app, @d6SliderValueChanging, true);
            app.d6Slider.Visible = 'off';
            app.th1EditFieldLabel = uilabel(app.UIFigure);
            app.th1EditFieldLabel.Visible = 'off';
            app.th1EditFieldLabel.Text = 'th1';
            app.th1EditField = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField.Visible = 'off';
            app.th2EditFieldLabel = uilabel(app.UIFigure);
            app.th2EditFieldLabel.Visible = 'off';
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField.Visible = 'off';
            app.th3EditFieldLabel = uilabel(app.UIFigure);
            app.th3EditFieldLabel.Visible = 'off';
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField.Visible = 'off';
            app.th4EditFieldLabel = uilabel(app.UIFigure);
            app.th4EditFieldLabel.Visible = 'off';
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField.Visible = 'off';
            app.th5EditFieldLabel = uilabel(app.UIFigure);
            app.th5EditFieldLabel.Visible = 'off';
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField.Visible = 'off';
            app.SleepButton = uibutton(app.UIFigure, 'push');
            app.SleepButton.ButtonPushedFcn = createCallbackFcn(app, @SleepButtonPushed, true);
            app.SleepButton.Visible = 'off';
            app.SleepButton.Text = 'Sleep';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            app.ShowthevaluesLabel = uilabel(app.UIFigure);
            app.ShowthevaluesLabel.Visible = 'off';
            app.ShowthevaluesLabel.Text = 'Show the values';
            app.TargetsLabel = uilabel(app.UIFigure);
            app.TargetsLabel.Visible = 'off';
            app.TargetsLabel.Text = 'Targets';
            app.th4EditField_2Label = uilabel(app.UIFigure);
            app.th4EditField_2Label.Visible = 'off';
            app.th4EditField_2Label.Text = 'th4';
            app.th4EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField_2.Visible = 'off';
            app.th1EditField_2Label = uilabel(app.UIFigure);
            app.th1EditField_2Label.Visible = 'off';
            app.th1EditField_2Label.Text = 'th1';
            app.th1EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField_2.Visible = 'off';
            app.th1EditField_2.Tooltip = {''};
            app.th2EditField_2Label = uilabel(app.UIFigure);
            app.th2EditField_2Label.Visible = 'off';
            app.th2EditField_2Label.Text = 'th2';
            app.th2EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField_2.Visible = 'off';
            app.th3EditField_2Label = uilabel(app.UIFigure);
            app.th3EditField_2Label.Visible = 'off';
            app.th3EditField_2Label.Text = 'th3';
            app.th3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField_2.Visible = 'off';
            app.th5EditField_2Label = uilabel(app.UIFigure);
            app.th5EditField_2Label.Visible = 'off';
            app.th5EditField_2Label.Text = 'th5';
            app.th5EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField_2.Visible = 'off';
            app.MovetoTargetButton = uibutton(app.UIFigure, 'push');
            app.MovetoTargetButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoTargetButtonPushed, true);
            app.MovetoTargetButton.Visible = 'off';
            app.MovetoTargetButton.Text = 'Move to Target Button';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'th1'; 'th2'; 'th3'; 'th4'; 'th5'};
            app.UITable.RowName = {};
            app.MovetosolutionEditFieldLabel = uilabel(app.UIFigure);
            app.MovetosolutionEditFieldLabel.Text = 'Move to  solution';
            app.MovetosolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.InverseKinematicsTableLabel = uilabel(app.UIFigure);
            app.InverseKinematicsTableLabel.Text = 'Inverse Kinematics Table';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {'X'; 'Y'; 'Z'; 'P'};
            app.UITable_2.RowName = {};
            app.TransformationMatrixLabel = uilabel(app.UIFigure);
            app.TransformationMatrixLabel.Text = 'Transformation Matrix';
            app.ComputeInverseButton = uibutton(app.UIFigure, 'push');
            app.ComputeInverseButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeInverseButtonPushed, true);
            app.ComputeInverseButton.Text = 'Compute Inverse';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW7_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end