classdef HW5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        CloseButton        matlab.ui.control.Button
        OpenButton         matlab.ui.control.Button
        SleepButton        matlab.ui.control.Button
        d6EditField        matlab.ui.control.NumericEditField
        d6EditFieldLabel   matlab.ui.control.Label
        th5EditField       matlab.ui.control.NumericEditField
        th5EditFieldLabel  matlab.ui.control.Label
        th4EditField       matlab.ui.control.NumericEditField
        th4EditFieldLabel  matlab.ui.control.Label
        th3EditField       matlab.ui.control.NumericEditField
        th3EditFieldLabel  matlab.ui.control.Label
        th2EditField       matlab.ui.control.NumericEditField
        th2EditFieldLabel  matlab.ui.control.Label
        th1EditField       matlab.ui.control.NumericEditField
        th1EditFieldLabel  matlab.ui.control.Label
        d6Slider           matlab.ui.control.Slider
        d6SliderLabel      matlab.ui.control.Label
        th5Slider          matlab.ui.control.Slider
        th5SliderLabel     matlab.ui.control.Label
        th4Slider          matlab.ui.control.Slider
        th4SliderLabel     matlab.ui.control.Label
        th2Slider          matlab.ui.control.Slider
        th2SliderLabel     matlab.ui.control.Label
        th3Slider          matlab.ui.control.Slider
        th3SliderLabel     matlab.ui.control.Label
        th1Slider          matlab.ui.control.Slider
        th1SliderLabel     matlab.ui.control.Label
        HomeButton         matlab.ui.control.Button
        InitializeButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description

        bot;

        Fb;
        Vb;

        F1;
        V1;

        F2;
        V2;

        F3;
        V3;

        F4;
        V4;

        F5;
        V5;

        F6;
        V6;

        F7;
        V7;

        th1 = 0;
        th2 = 0;
        th3 = 0;
        th4 = 0;
        th5 = 0;
        d6 = 0;

        error=0;
        sserror=1;

    end
    
    methods (Access = private)
        
        function [] = drawRobot(app)
            
            cla
            plot3(0,0,0,'r*','MarkerSize',10)

            syms computeT(a, alpha, d, th)

            c = cos(th); s = sin(th);
            ca = cos(alpha); sa = sin(alpha);
            
            ah = alpha;
            
            computeT = symfun([c        -s         0          a; ...
                               s*ca   c*ca     -sa     -sa*d;
                               s*sa   c*sa     ca      ca*d;
                               0        0          0         1] , [a, ah, d, th] );
             
            syms L1 L2 L3 th1 th2 th3 th4 th5 real
            A = [0 0 L1 L2 0];
            ALPHA = [0 -90 0 0 -90] * pi/180;
            AH = ALPHA;
            D = [0 0 0 0 L3];
            TH = [th1 th2 th3 th4 th5];

            %Link base
            Tba = eye(4);
            pb = Tba*app.Vb;

            fcs =['g'];
            patch('faces',app.Fb, 'vertices',pb(1:3,:)','facecolor',fcs)

            %Link1
            % T1A = eye(4);
            % Trot = eye(4);
            % 
            % Trot(1:3,1:3) = rotz(app.th1);
            % T1A = T1A*Trot;

            T1A(:,:,1) = computeT(A(1), ALPHA(1), D(1), TH(1));
            T1A = simplify(T1A);
 
            p1 = T1A*app.V1;
            fcs =['r'];
            patch('faces',app.F1, 'vertices',p1(1:3,:)','facecolor',fcs)

             %Link2 
             % T21 = eye(4);
             % Trot = eye(4);
             % Trot(1:3,4) = [0 0 26.73]';
             % 
             % Trot(1:3,1:3) = rotx(app.th2-96);
             % T21 = T21 * Trot;
             
             T21(:,:,2) = computeT(A(2), ALPHA(2), D(2), TH(2));
             T21 = simplify(T21);
             
             p2 = T1A*T21*app.V2;
             fcs =['c'];
             patch('faces',app.F2, 'vertices',p2(1:3,:)','facecolor',fcs)

             %Link3  
             % T32 = eye(4);
             % Trot = eye(4);
             % Trot(1:3,4) = [0.6 -190.47  25.2]';
             % 
             % Trot(1:3,1:3) = rotx(app.th3+97);
             % T32 = T32*Trot;

             T32(:,:,3) = computeT(A(3), ALPHA(3), D(3), TH(3));
             T32 = simplify(T32);

             p3 = T1A*T21*T32*app.V3;
             fcs =['b']; 
             patch('faces',app.F3, 'vertices',p3(1:3,:)','facecolor',fcs)


             %Link4  
             % T43 = eye(4);
             % Trot = eye(4);
             % Trot(1:3,4) = [0.28 195.65  -4]';
             % 
             % Trot(1:3,1:3) = rotx(app.th4+17);
             % T43 = T43*Trot;

             T43(:,:,4) = computeT(A(4), ALPHA(4), D(4), TH(4));
             T43 = simplify(T43);

             p4 = T1A*T21*T32*T43*app.V4;
             fcs =['y']; 
             patch('faces',app.F4, 'vertices',p4(1:3,:)','facecolor',fcs)

             %Link5 
             % T54 = eye(4);
             % Trot = eye(4);
             % Trot(1:3,4) = [0 59  -30.5]';
             % 
             % Trot(1:3,1:3) =  rotx(-20.5) * roty(app.th5); 
             % T54 = T54*Trot;\
             
             T54(:,:,5) = computeT(A(5), ALPHA(5), D(5), TH(5));
             T54 = simplify(T54);

             p5 = T1A*T21*T32*T43*T54*app.V5;
             fcs =['m']; 
             patch('faces',app.F5, 'vertices',p5(1:3,:)','facecolor',fcs)

             %Link6
             T65 = eye(4);
             Trot = eye(4);
             Trot(1:3,4) = [(app.d6 - 19.75)  64.07  3]';
           
             T65 = T65*Trot;

             p6 = T1A*T21*T32*T43*T54*T65*app.V6;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F6, 'vertices',p6(1:3,:)','facecolor',fcs)

             %Link7
             T76 = eye(4);
             Trot = eye(4);
             Trot(1:3,4) = [(-app.d6 + 19.75)  64.07 3]';
           
             T76 = T76*Trot;

             p7 = T1A*T21*T32*T43*T54*T76*app.V7;
             fcs =[0.85 0.325 0.098]; 
             patch('faces',app.F7, 'vertices',p7(1:3,:)','facecolor',fcs)
             
             
        end
        
        function []= close_loop_control(app)
            joint_positions=[app.th1 -1*app.th2 -1*app.th3 -1*(app.th4) app.th5]*pi/180;
            app.bot.arm.set_joint_positions(joint_positions);
            app.bot.arm.capture_joint_positions();
            app.error=app.bot.arm.joint_commands'- joint_positions;
            app.sserror=dot(app.error,app.error); 
       
        end
        
        function [] = go_home(app)
              'Moving to home position'
              
             while( (app.th1 < -1 || app.th1 > 1) || (app.th2 < -1 || app.th2 > 1)  || (app.th3 < -1 || app.th3 > 1) || (app.th4 < -1 || app.th4 > 1)  || (app.th5 < -1 || app.th5 > 1)   )
   
                    if(app.th1 < -1)
                        app.th1 = app.th1 + 0.3;
                  
                    elseif(app.th1 > 1)
                        app.th1 = app.th1 - 0.3;
                    end
                  
                    if(app.th2 < -1)
                        app.th2 = app.th2 + 0.3;

                    elseif(app.th2 > 1)
                        app.th2 = app.th2 - 0.3;

                    end
               
                    if(app.th3 < -1)
                        app.th3 = app.th3 + 0.3;
                   
                    elseif(app.th3 > 1)
                        app.th3 = app.th3 - 0.3;
                        
                    end
                  
                    if(app.th4 < -1)
                        app.th4 = app.th4 + 0.3;
                      
                    elseif(app.th4 > 1)
                        app.th4 = app.th4 - 0.3;
                      
                    end
               
                    if(app.th5 < -1)
                        app.th5 = app.th5 + 0.3;

                    elseif(app.th5 > 1)
                        app.th5 = app.th5 - 0.3;
                    
                    end

                    
               flag = 0;
               while(app.sserror > 0.3 || flag == 0)
                   flag = 1;
                   app.close_loop_control();

               end

               'Drawing robot'
               app.drawRobot()
               pause(0.05)
               
            end
            
            app.bot.gripper.open()
            app.d6 = 20; 
        end

       
        function [] = go_sleep(app)
            'Moving to sleep position'

            while( (app.th1 < -1 || app.th1 > 1) || (app.th2 < 97 || app.th2 > 99)  || (app.th3 < -91 || app.th3 > -89) || (app.th4 < -45 || app.th4 > -43)  || (app.th5 < -1 || app.th5 > 1)   )
     
                    if(app.th1 < -1)
                        app.th1 = app.th1 + 0.3;
                        
                    elseif(app.th1 > 1)
                        app.th1 = app.th1 - 0.3;
                      
                    end
                
                    if(app.th2 < 97)
                        app.th2 = app.th2 + 0.3;
                      
                    elseif(app.th2 > 99)
                        app.th2 = app.th2 - 0.3;
                   
                    end
                 
                    if(app.th3 < -91)
                        app.th3 = app.th3 + 0.3;
                   
                    elseif(app.th3 > -89)
                        app.th3 = app.th3 - 0.3;
                  
                    end
               
                    if(app.th4 < -45)
                        app.th4 = app.th4 + 0.3;
                     
                    elseif(app.th4 > -43)
                        app.th4 = app.th4 - 0.3;
                     
                    end
              
                    if(app.th5 < -1)
                        app.th5 = app.th5 + 0.3;
          
                    elseif(app.th5 > 1)
                        app.th5 = app.th5 - 0.3;
                
                    end
                    
               flag = 0;
               while(app.sserror > 0.3 || flag == 0)
                   flag = 1;
                   app.close_loop_control();

               end

               'Drawing robot'
               app.drawRobot()
               pause(0.05)
               
            end
            
            app.bot.gripper.close();
            app.d6 = 0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            rosshutdown 

            close all
            plot3(0,0,0)
            s = 600;
            axis ([-s s -s s -s s ])
            grid on
            xlabel('x')
            ylabel('y')
            zlabel('z')
            axis square
            hold on
            cla

            [app.Fb, app.Vb] = stlread_mod("Base.STL");
            [app.F1, app.V1] = stlread_mod("Link1.STL");
            [app.F2, app.V2] = stlread_mod("Link2.STL");
            [app.F3, app.V3] = stlread_mod("Link3.STL");
            [app.F4, app.V4] = stlread_mod("Link4.STL");
            [app.F5, app.V5] = stlread_mod("Link5.STL");
            [app.F6, app.V6] = stlread_mod("Link6.STL");
            [app.F7, app.V7] = stlread_mod("Link7.STL");
            
            %Default position due to coordinate systems
            app.drawRobot()
            
            app.InitializeButton.Visible="off";
            app.HomeButton.Visible="on";
            app.SleepButton.Visible = "on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";


            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

            app.d6EditFieldLabel.Visible="off";
            app.d6EditField.Visible="off";
            

            app.bot = InterbotixManipulatorXS("rx200", "arm", "gripper");
            
            %Very initial position
            app.bot.arm.capture_joint_positions();
            initial_pos = app.bot.arm.joint_commands' * 180/pi

            app.th1 = initial_pos(1,1);
           
            app.th2 = -1*initial_pos(1,2) - 5;
          
            app.th3 = -1*initial_pos(1,3);
            
            app.th4 = -1*initial_pos(1,4) + 2;
            
            app.th5 = initial_pos(1,5);
      
            app.bot.gripper.open();
            app.d6=20;

            app.drawRobot()

            app.go_home();

        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
           
            app.go_home();
            
            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;
            app.d6Slider.Value = 0;

            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;
            app.d6EditField.Value = 0;
            
            app.SleepButton.Visible ="on";
            app.OpenButton.Visible="on";
            app.CloseButton.Visible="on";


            app.th1Slider.Visible="on";
            app.th1SliderLabel.Visible="on";

            app.th2Slider.Visible="on";
            app.th2SliderLabel.Visible="on";

            app.th3Slider.Visible="on";
            app.th3SliderLabel.Visible="on";

            app.th4SliderLabel.Visible="on";
            app.th4Slider.Visible="on";

            app.th5SliderLabel.Visible="on";
            app.th5Slider.Visible="on";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="on";
            app.th1EditField.Visible ="on";
        
            app.th2EditFieldLabel.Visible="on";
            app.th2EditField.Visible="on";

            app.th3EditFieldLabel.Visible="on";
            app.th3EditField.Visible="on";

            app.th4EditFieldLabel.Visible="on";
            app.th4EditField.Visible="on";

            app.th5EditFieldLabel.Visible="on";
            app.th5EditField.Visible="on";

            app.d6EditFieldLabel.Visible="off";
            app.d6EditField.Visible="off";
            
           
        end

        % Value changing function: th1Slider
        function th1SliderValueChanging(app, event)
            'Slider 1 is being moved'
            flag = 0;
            
            app.th1 = event.Value;

            while(app.sserror > 0.3 || flag == 0)
           
            flag = 1;
            app.close_loop_control();
  
            end

            app.th1EditField.Value = event.Value;
            app.drawRobot();
        end

        % Value changing function: th2Slider
        function th2SliderValueChanging(app, event)
            'Slider 2 is being moved'
            flag = 0;

            app.th2 = -1*event.Value;
            
            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();

            end

            app.th2EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th3Slider
        function th3SliderValueChanging(app, event)
            'Slider 3 is being moved'
            flag = 0;
            app.th3 = event.Value;
            
            while(app.sserror > 0.3 || flag == 0)
           
            flag = 1;
            app.close_loop_control();
  
            end

            app.th3EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th4Slider
        function th4SliderValueChanging(app, event)
            'Slider 4 is being moved'
            flag = 0;
            app.th4 =  event.Value;

            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();
  
            end

            app.th4EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: th5Slider
        function th5SliderValueChanging(app, event)
            'Slider 5 is being moved'
            flag = 0;
            app.th5 = event.Value;

            while(app.sserror > 0.3 || flag == 0)
            
            flag = 1;
            app.close_loop_control();
  
            end

            app.th5EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Value changing function: d6Slider
        function d6SliderValueChanging(app, event)
            app.d6 = event.Value;
            app.d6EditField.Value = event.Value;
            app.drawRobot();
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.bot.gripper.open();
            app.d6=20;
            app.drawRobot();
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.bot.gripper.close();
            app.d6=0;
            app.drawRobot();
        end

        % Button pushed function: SleepButton
        function SleepButtonPushed(app, event)
            
            app.go_home();

            app.th1Slider.Value = 0;
            app.th2Slider.Value = 0;
            app.th3Slider.Value = 0;
            app.th4Slider.Value = 0;
            app.th5Slider.Value = 0;
            app.d6Slider.Value = 0;

            app.th1EditField.Value = 0;
            app.th2EditField.Value = 0;
            app.th3EditField.Value = 0;
            app.th4EditField.Value = 0;
            app.th5EditField.Value = 0;
            app.d6EditField.Value = 0;

            app.go_sleep();
            
            app.SleepButton.Visible = "off";
            app.OpenButton.Visible="off";
            app.CloseButton.Visible="off";


            app.th1Slider.Visible="off";
            app.th1SliderLabel.Visible="off";

            app.th2Slider.Visible="off";
            app.th2SliderLabel.Visible="off";

            app.th3Slider.Visible="off";
            app.th3SliderLabel.Visible="off";

            app.th4SliderLabel.Visible="off";
            app.th4Slider.Visible="off";

            app.th5SliderLabel.Visible="off";
            app.th5Slider.Visible="off";

            app.d6SliderLabel.Visible="off";
            app.d6Slider.Visible="off";

            app.th1EditFieldLabel.Visible="off";
            app.th1EditField.Visible ="off";
        
            app.th2EditFieldLabel.Visible="off";
            app.th2EditField.Visible="off";

            app.th3EditFieldLabel.Visible="off";
            app.th3EditField.Visible="off";

            app.th4EditFieldLabel.Visible="off";
            app.th4EditField.Visible="off";

            app.th5EditFieldLabel.Visible="off";
            app.th5EditField.Visible="off";

            app.d6EditFieldLabel.Visible="off";
            app.d6EditField.Visible="off";
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Visible = 'off';
            app.HomeButton.Text = 'Home';
            app.th1SliderLabel = uilabel(app.UIFigure);
            app.th1SliderLabel.Visible = 'off';
            app.th1SliderLabel.Text = 'th1';
            app.th1Slider = uislider(app.UIFigure);
            app.th1Slider.Limits = [-90 90];
            app.th1Slider.ValueChangingFcn = createCallbackFcn(app, @th1SliderValueChanging, true);
            app.th1Slider.Visible = 'off';
            app.th3SliderLabel = uilabel(app.UIFigure);
            app.th3SliderLabel.Visible = 'off';
            app.th3SliderLabel.Text = 'th3';
            app.th3Slider = uislider(app.UIFigure);
            app.th3Slider.Limits = [-45 45];
            app.th3Slider.ValueChangingFcn = createCallbackFcn(app, @th3SliderValueChanging, true);
            app.th3Slider.Visible = 'off';
            app.th2SliderLabel = uilabel(app.UIFigure);
            app.th2SliderLabel.Visible = 'off';
            app.th2SliderLabel.Text = 'th2';
            app.th2Slider = uislider(app.UIFigure);
            app.th2Slider.Limits = [-45 45];
            app.th2Slider.ValueChangingFcn = createCallbackFcn(app, @th2SliderValueChanging, true);
            app.th2Slider.Visible = 'off';
            app.th4SliderLabel = uilabel(app.UIFigure);
            app.th4SliderLabel.Visible = 'off';
            app.th4SliderLabel.Text = 'th4';
            app.th4Slider = uislider(app.UIFigure);
            app.th4Slider.Limits = [-45 45];
            app.th4Slider.ValueChangingFcn = createCallbackFcn(app, @th4SliderValueChanging, true);
            app.th4Slider.Visible = 'off';
            app.th5SliderLabel = uilabel(app.UIFigure);
            app.th5SliderLabel.Visible = 'off';
            app.th5SliderLabel.Text = 'th5';
            app.th5Slider = uislider(app.UIFigure);
            app.th5Slider.Limits = [-120 120];
            app.th5Slider.ValueChangingFcn = createCallbackFcn(app, @th5SliderValueChanging, true);
            app.th5Slider.Visible = 'off';
            app.d6SliderLabel = uilabel(app.UIFigure);
            app.d6SliderLabel.Visible = 'off';
            app.d6SliderLabel.Text = 'd6';
            app.d6Slider = uislider(app.UIFigure);
            app.d6Slider.Limits = [-360 360];
            app.d6Slider.ValueChangingFcn = createCallbackFcn(app, @d6SliderValueChanging, true);
            app.d6Slider.Visible = 'off';
            app.th1EditFieldLabel = uilabel(app.UIFigure);
            app.th1EditFieldLabel.Visible = 'off';
            app.th1EditFieldLabel.Text = 'th1';
            app.th1EditField = uieditfield(app.UIFigure, 'numeric');
            app.th1EditField.Visible = 'off';
            app.th2EditFieldLabel = uilabel(app.UIFigure);
            app.th2EditFieldLabel.Visible = 'off';
            app.th2EditFieldLabel.Text = 'th2';
            app.th2EditField = uieditfield(app.UIFigure, 'numeric');
            app.th2EditField.Visible = 'off';
            app.th3EditFieldLabel = uilabel(app.UIFigure);
            app.th3EditFieldLabel.Visible = 'off';
            app.th3EditFieldLabel.Text = 'th3';
            app.th3EditField = uieditfield(app.UIFigure, 'numeric');
            app.th3EditField.Visible = 'off';
            app.th4EditFieldLabel = uilabel(app.UIFigure);
            app.th4EditFieldLabel.Visible = 'off';
            app.th4EditFieldLabel.Text = 'th4';
            app.th4EditField = uieditfield(app.UIFigure, 'numeric');
            app.th4EditField.Visible = 'off';
            app.th5EditFieldLabel = uilabel(app.UIFigure);
            app.th5EditFieldLabel.Visible = 'off';
            app.th5EditFieldLabel.Text = 'th5';
            app.th5EditField = uieditfield(app.UIFigure, 'numeric');
            app.th5EditField.Visible = 'off';
            app.d6EditFieldLabel = uilabel(app.UIFigure);
            app.d6EditFieldLabel.Visible = 'off';
            app.d6EditFieldLabel.Text = 'd6';
            app.d6EditField = uieditfield(app.UIFigure, 'numeric');
            app.d6EditField.Visible = 'off';
            app.SleepButton = uibutton(app.UIFigure, 'push');
            app.SleepButton.ButtonPushedFcn = createCallbackFcn(app, @SleepButtonPushed, true);
            app.SleepButton.Visible = 'off';
            app.SleepButton.Text = 'Sleep';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Visible = 'off';
            app.OpenButton.Text = 'Open';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Visible = 'off';
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HW5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end